classdef AlignMe_2020b_dev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AlignMeArrayManipulationAppLabel  matlab.ui.control.Label
        ArrayAlignmentPanel         matlab.ui.container.Panel
        dxEditFieldLabel            matlab.ui.control.Label
        dyEditFieldLabel            matlab.ui.control.Label
        dzEditFieldLabel            matlab.ui.control.Label
        ScaleEditFieldLabel         matlab.ui.control.Label
        xrotEditFieldLabel          matlab.ui.control.Label
        yrotEditFieldLabel          matlab.ui.control.Label
        zrotEditFieldLabel          matlab.ui.control.Label
        ResetDropDownLabel          matlab.ui.control.Label
        ResetDropDown               matlab.ui.control.DropDown
        TurnOptodeLabelsOnButton    matlab.ui.control.StateButton
        RelaxArrayButton            matlab.ui.control.Button
        SaveAlignmentButton         matlab.ui.control.Button
        flipXCheckBox               matlab.ui.control.CheckBox
        flipYCheckBox               matlab.ui.control.CheckBox
        flipZCheckBox               matlab.ui.control.CheckBox
        PressEntertoupdateLabel     matlab.ui.control.Label
        zrotEditField               matlab.ui.control.NumericEditField
        dzEditField                 matlab.ui.control.NumericEditField
        yrotEditField               matlab.ui.control.NumericEditField
        dyEditField                 matlab.ui.control.NumericEditField
        xrotEditField               matlab.ui.control.NumericEditField
        dxEditField                 matlab.ui.control.NumericEditField
        ScaleEditField              matlab.ui.control.NumericEditField
        PreviousButton              matlab.ui.control.Button
        NextButton                  matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        XZ_ViewButton               matlab.ui.control.Button
        XY_ViewButton               matlab.ui.control.Button
        YZ_ViewButton               matlab.ui.control.Button
        RotateCCWButton             matlab.ui.control.Button
        RotateCWButton              matlab.ui.control.Button
        AtlasScalingPanel           matlab.ui.container.Panel
        ScaleMeshButton             matlab.ui.control.Button
        LefttoRightPADistEditFieldLabel  matlab.ui.control.Label
        HeadCircumferenceEditFieldLabel  matlab.ui.control.Label
        ResetMeshButton             matlab.ui.control.Button
        MeshscaledCheckBox          matlab.ui.control.CheckBox
        NasiontoInionDistEditField  matlab.ui.control.NumericEditField
        LefttoRightPADistEditField  matlab.ui.control.NumericEditField
        HeadCircumferenceEditField  matlab.ui.control.NumericEditField
        NasiontoInionDistEditFieldLabel  matlab.ui.control.Label
        XZ_ViewButton_2             matlab.ui.control.Button
        XY_ViewButton_2             matlab.ui.control.Button
        YZ_ViewButton_2             matlab.ui.control.Button
        Switch                      matlab.ui.control.RockerSwitch
        ViewLabel                   matlab.ui.control.Label
        ViewEditField               matlab.ui.control.NumericEditField
        ViewEditField_2             matlab.ui.control.NumericEditField
        UpdateViewButton            matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        atlasFiducials  % Location of atlas nasion, inion, preauricular points, and Cz
        tpos            % Source and detector positions 
        mesh            % Head mesh
        pM              % Parameters for head mesh plotting
        Ns              % Number of total sources
        Nd              % Number of total detectors
        paramsFoci      % Paramaters for Draw Foci function
        pad             % Cap info
        photoPath       % Path of photos for imaging session     
        photos          % Array with photos
        listOfImages    % Images saved in a list
        imageNumber=1;  % Number of the image being displayed
        imageType;      % File type of participant images
        posInit         % Initial position of plot
        pos             % position of current plot
        initCm
        plots           % UI axis plot
        prev_state      % Parameters and source-detector positions from prevous state
        values          % Cap manipulation parameters
        tpos2           % Updated SD positions, var is passed from startup to update and undo buttons only
        bH_values       % Cap manipulation parameters for translation to behind the head mesh
        fH_values       % Cap manipulation parameters for translation to front of head mesh
        init_values     % Cap manipulation parameters for initial positioning in front of head
        init_atlas      % Initial atlas parameters
        alltpos2        % Source-detector positions after all optodes are changed, used for getSubsetValues
        W               % Affine transform matrix
        relaxedVar=0;      % Variable for telling draw_foci if relaxed mesh has been performed
        rotY_all
        rotZ_all
        lim
        meshMax
        meshT
        defaultLim
    end
    
    properties (Access = public)
        ds      % DataStorage object
        dS_UI   % user input for scale for relaxation process 
    end
    
    methods (Access = private)
        
        function [values,tpos2] = getValues(app)
            values = [];
            
            values.dx = app.dxEditField.Value;
            values.dy = app.dyEditField.Value;
            values.dz = app.dzEditField.Value;
            values.Scale = app.ScaleEditField.Value;
            values.dxTh = app.xrotEditField.Value;
            values.dyTh = app.yrotEditField.Value;
            values.dzTh = app.zrotEditField.Value;
            values.flipX = app.flipXCheckBox.Value;
            values.flipY = app.flipYCheckBox.Value;
            values.flipZ = app.flipZCheckBox.Value;
            
            tpos2=rotate_cap(app.tpos, [values.dxTh, values.dyTh, values.dzTh]);
            tpos2(:,1)=tpos2(:,1)+values.dx;
            tpos2(:,2)=tpos2(:,2)+values.dy;
            tpos2(:,3)=tpos2(:,3)+values.dz;
            tpos2=scale_cap(tpos2, values.Scale);
            tpos2=flip_cap(tpos2, [values.flipX, values.flipY, values.flipZ]);
        end
        
        function [values,tpos2] = getSubsetValues(app)
            values = [];
            fromSrc = get(app.SrcFrom, 'Value');
            toSrc = get(app.SrcTo, 'Value');
            fromDet = get(app.DetFrom, 'Value')+app.Ns;
            toDet = get(app.DetTo, 'Value')+app.Ns;
            
            values.dx = app.dxEditField.Value;
            values.dy = app.dyEditField.Value;
            values.dz = app.dzEditField.Value;
            values.Scale = app.ScaleEditField.Value;
            values.dxTh = app.xrotEditField.Value;
            values.dyTh = app.yrotEditField.Value;
            values.dzTh = app.zrotEditField.Value;
            values.flipX = app.flipXCheckBox.Value;
            values.flipY = app.flipYCheckBox.Value;
            values.flipZ = app.flipZCheckBox.Value;
            
            tpos2 = app.alltpos2;
            
            tpos2(fromSrc:toSrc,:)=rotate_cap(tpos2(fromSrc:toSrc, :), [values.dxTh, values.dyTh, values.dzTh]);
            tpos2(fromDet:toDet,:)=rotate_cap(tpos2(fromDet:toDet, :), [values.dxTh, values.dyTh, values.dzTh]);
            
            tpos2(fromSrc:toSrc,1)=tpos2(fromSrc:toSrc,1)+values.dx;
            tpos2(fromDet:toDet,1)=tpos2(fromDet:toDet,1)+values.dx;
            
            tpos2(fromSrc:toSrc,2)=tpos2(fromSrc:toSrc,2)+values.dy;
            tpos2(fromDet:toDet,2)=tpos2(fromDet:toDet,2)+values.dy;
            
            tpos2(fromSrc:toSrc,3)=tpos2(fromSrc:toSrc,3)+values.dz;
            tpos2(fromDet:toDet,3)=tpos2(fromDet:toDet,3)+values.dz;
            
            tpos2(fromSrc:toSrc,:)=scale_cap(tpos2(fromSrc:toSrc,:), values.Scale);
            tpos2(fromDet:toDet,:)=scale_cap(tpos2(fromDet:toDet,:), values.Scale);
            
            tpos2(fromSrc:toSrc,:)=flip_cap(tpos2(fromSrc:toSrc,:), [values.flipX, values.flipY, values.flipZ]);
            tpos2(fromDet:toDet,:)=flip_cap(tpos2(fromDet:toDet,:), [values.flipX, values.flipY, values.flipZ]);
            
        end
        
        function setValues(app,values)
            app.dxEditField.Value=values.dx;
            app.dyEditField.Value=values.dy;
            app.dzEditField.Value=values.dz;
            app.ScaleEditField.Value=values.Scale;
            app.xrotEditField.Value=values.dxTh;
            app.yrotEditField.Value=values.dyTh;
            app.zrotEditField.Value=values.dzTh;
            app.flipXCheckBox.Value=values.flipX;
            app.flipYCheckBox.Value=values.flipY;
            app.flipZCheckBox.Value=values.flipZ;
        end
    
        function setToInitialCapPos(app)
            app.dxEditField.Value=0;
            app.dyEditField.Value=0;
            app.dzEditField.Value=0;
            app.ScaleEditField.Value=1;
            app.xrotEditField.Value=0;
            app.yrotEditField.Value=0;
            app.zrotEditField.Value=0;
            app.flipXCheckBox.Value=0;
            app.flipYCheckBox.Value=0;
            app.flipZCheckBox.Value=0;
        end
        
        function setToFrontOfHead(app)
            app.dxEditField.Value = app.fH_values.dx;
            app.dyEditField.Value = app.fH_values.dy;
            app.dzEditField.Value = app.fH_values.dz;
            app.ScaleEditField.Value=1;
            app.xrotEditField.Value=0;
            app.yrotEditField.Value=0;
            app.zrotEditField.Value=0;
            app.flipXCheckBox.Value=0;
            app.flipYCheckBox.Value=0;
            app.flipZCheckBox.Value=0;
        end
        function setToBehindTheHead(app)
            app.dxEditField.Value=app.bH_values.dx;
            app.dyEditField.Value=app.bH_values.dy;
            app.dzEditField.Value=app.bH_values.dz;
            app.ScaleEditField.Value=1;
            app.xrotEditField.Value=0;
            app.yrotEditField.Value=0;
            app.zrotEditField.Value=0;
            app.flipXCheckBox.Value=0;
            app.flipYCheckBox.Value=0;
            app.flipZCheckBox.Value=0;
        end
    
        function setToOrigin(app)
            app.dxEditField.Value = 0;
            app.dyEditField.Value = 0;
            app.dzEditField.Value = 0;
            app.ScaleEditField.Value = 1;
            app.xrotEditField.Value = 0;
            app.yrotEditField.Value = 0;
            app.zrotEditField.Value = 0;
            app.flipXCheckBox.Value = 0;
            app.flipYCheckBox.Value = 0;
            app.flipZCheckBox.Value = 0;
        end

        function deletePlots(app)
            if app.plots.hh(:)~=0
                delete(app.plots.hh(:));app.plots.hh(:)=0;  % delete grid from figure
            end
            
            if isfield(app.plots, 'top')
                if app.plots.top(:)~=0
                    delete(app.plots.top(:));app.plots.top(:)=0;  % delete grid from figure
                end
            end
            
            if isfield(app.plots, 'bottom')
                if app.plots.bottom(:)~=0
                    delete(app.plots.bottom(:));app.plots.bottom(:)=0;  % delete grid from figure
                end
            end
            
            if app.plots.dots(:)==0
                % do nothing
            else
                delete(app.plots.dots(:));app.plots.dots(:)=0;   % delete plotSD markers
            end
            if all(ismember(app.plots.ts(:),0)) && all(ismember(app.plots.td(:),0))
                % do nothing
            else
                delete(app.plots.ts(:));app.plots.ts(:)=0;  % delete sources text from figure
                delete(app.plots.td(:));app.plots.td(:)=0;  % delete detectors text from figure
            end
        end
        
        function draw_foci(app,foci,params)
            % This function draws cylinders/spheres of a specified color and radius at the
            % location given by foci. Params fields include: color, faces,
            % length, and radius. Only foci is strictly required as color and radius each
            % have default values ([0,0,0], and 5, repectively).
            %   params.color are rows of RGB values contained within [0,1].
            %   params.lighting can be any of 'phong' (default),'flat'
            
            % Parameters and Initialization
            if ~isfield(params,'faces'),   params.faces=10;end
            if ~isfield(params,'lighting'),params.lighting='flat';end
            if ~isfield(params,'AmbientStrength'),params.AmbientStrength=0.3;end
            
            Nfoci=size(foci,1); % foci is (Ns+Nd)x3
            fig_handle = params.fig_handle;
            Ns=size(app.pad.optodes.spos3,1);
            Nd=size(app.pad.optodes.dpos3,1);

            if ~isfield(params,'color'),params.color=zeros(Nfoci,3);end % color is a (Ns+Nd)x3 arrays where color(1) = pink, color(2:Ns) = dark red, color(Ns+1) = light blue, color(Ns+2:end) = dark blue,
            if size(params.color,1)~=Nfoci,params.color=repmat(params.color,[Nfoci,1]);end
            if ~isfield(params,'radius'),params.radius=ones(Nfoci,1).*5;end
            if ~isfield(params,'length'),params.length=ones(Nfoci,1).*5;end
            if size(params.radius,1)~=Nfoci,params.radius=repmat(params.radius,[Nfoci,1]);end % (Ns+Nd)x1
            
            if app.TurnOptodeLabelsOnButton.Value == 1
                plotSD(app,foci(1:app.Ns,:),foci((app.Ns+1):end,:),'discs',app.UIAxes); % draws center justified numbers
                app.TurnOptodeLabelsOnButton.Text = "Optode Labels Off";
            end
            
            if app.Switch.Value == 0
                [x,y,z]=cylinder(1,params.faces);
                r2d = 180/pi;
                orient1=[mean(x(2,:))-mean(x(1,:)),mean(y(2,:))-mean(y(1,:)),...% Orientation of cylinder
                         mean(z(2,:))-mean(z(1,:))];
                [phi0,theta0,r0]=cart2sph(orient1(1),orient1(2),orient1(3));    % Initial angles for cylinder
                
                % Draw the foci
                for j=1:Nfoci % iterating from 1:(Ns+Nd)
                    rad=params.radius(j);
                    L=params.length(j);
    
                    X=reshape(rad*x,[],1);
                    Y=reshape(rad*y,[],1);
                    Z=reshape(L*z,[],1);
                    
                    nearestNode = dsearchn(app.mesh.nodes, foci(j,:));
                    dr = foci(j,:) - app.mesh.nodes(nearestNode,:);
                    
                    [phi,theta,R]=cart2sph(dr(:,1),dr(:,2),dr(:,3));            % Angles for adjustment
                    beta = (theta - theta0)*r2d;
                    gamma = -(phi-phi0)*r2d;
                    pos2 = [X, Y, Z];
                    rotY = [cosd(beta) 0 sind(beta); 0 1 0; -sind(beta) 0 cosd(beta)];
                    rotZ = [cosd(gamma) -sind(gamma) 0; sind(gamma) cosd(gamma) 0; 0 0 1];
                    
                    if app.relaxedVar == 0
                        pos2 = pos2 * rotY * rotZ;
                        app.rotY_all(:,:,j) = rotY;
                        app.rotZ_all(:,:,j) = rotZ;
                    else
                        pos2 = pos2 * app.rotY_all(:,:,j) * app.rotZ_all(:,:,j);
                    end
                    
                    X=reshape(pos2(:,1),2,[]);
                    Y=reshape(pos2(:,2),2,[]);
                    Z=reshape(pos2(:,3),2,[]);
                    X=X-mean(X(1,:));
                    Y=Y-mean(Y(1,:));
                    Z=Z-mean(Z(1,:));
    
                    fvc = surf2patch(X+foci(j,1),...
                        Y+foci(j,2),Z+foci(j,3)); 
                    app.plots.hh(j)=patch(app.UIAxes, fvc,...
                        'EdgeColor',params.color(j,:),'FaceColor',params.color(j,:),...
                        'EdgeAlpha',0, 'FaceLighting',params.lighting,...
                        'AmbientStrength', 0.25, 'DiffuseStrength', .75, 'SpecularStrength', .1);
                    
                    app.plots.top(j) = fill3(app.UIAxes,X(1,:)+foci(j,1),Y(1,:)+foci(j,2),Z(1,:)+foci(j,3),params.color(j,:));
                    app.plots.bottom(j) = fill3(app.UIAxes,X(2,:)+foci(j,1),Y(2,:)+foci(j,2),Z(2,:)+foci(j,3),params.color(j,:));
    
                end
            else
                [x,y,z]=sphere(params.faces); %x, y, z are 11x11
            
                %% Draw the foci
                for j=1:Nfoci % iterating from 1:(Ns+Nd)
                    rad=params.radius(j);
                    fvc = surf2patch(rad*x+foci(j,1),rad*y+foci(j,2),rad*z+foci(j,3)); %1x1 struct of faces = 100x4 and vertices = 121x3, which update every iteration
                    app.plots.hh(j)=patch(app.UIAxes, fvc,...
                        'EdgeColor',params.color(j,:),'FaceColor',params.color(j,:),...
                        'EdgeAlpha',0, 'FaceLighting',params.lighting,...
                        'AmbientStrength', 0.25, 'DiffuseStrength', .75, 'SpecularStrength', 0.9, 'SpecularExponent',10);
                end
            end
            
            axis(app.UIAxes,'image')
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end
        
        function plotSD(app,spos,dpos,type,F)
            textColor = 'k';
            sColor = [1,0,0];
            dColor = [0,0,1];

            TextSize=20;
            spos = spos + 5;
            dpos = dpos + 5;
            if size(spos,2)==2
                for i=1:size(spos,1)
                    app.plots.ts(i)=text(app.UIAxes,spos(i,1),spos(i,2),num2str(i),'Color',[1 .75 .75],...
                        'HorizontalAlignment','center','FontSize',TextSize,'FontWeight','bold');
                end
                for i=1:size(dpos,1)
                    app.plots.td(i)=text(app.UIAxes,dpos(i,1),dpos(i,2),num2str(i),'Color',[.55 .55 1],...
                        'HorizontalAlignment','center','FontSize',TextSize,'FontWeight','bold');
                end
            else
                for i=1:size(spos,1)
                    app.plots.ts(i)=text(app.UIAxes,spos(i,1),spos(i,2),spos(i,3),num2str(i),'Color',sColor,...
                        'HorizontalAlignment','center', 'FontSize', TextSize, 'FontWeight', 'bold'); 
                end
                for i=1:size(dpos,1)
                    app.plots.td(i)=text(app.UIAxes,dpos(i,1),dpos(i,2),dpos(i,3),num2str(i),'Color',dColor,...
                        'HorizontalAlignment','center', 'FontSize', TextSize, 'FontWeight','bold');
                end
            end
        end


        function f = ellipsoidCostFunction(app, x, HC, NzIz, LpaRpa)
            a = x(1); b=x(2); c=x(3);
            
            h = (a-b)^2/(a+b)^2;
            hc = pi*(a+b)*(1+(3*h/(10+sqrt(4-3*h))));
            
            h = (b-c)^2/(b+c)^2;
            alar = (pi/2)*(b+c)*(1+(3*h/(10+sqrt(4-3*h))));
            
            h = (a-c)^2/(a+c)^2;
            iznz = (pi/2)*(a+c)*(1+(3*h/(10+sqrt(4-3*h))));
            
            f = (hc - HC)^2 + (iznz-NzIz)^2 + (alar-LpaRpa)^2;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ds)
            app.ds = ds;
            app.tpos=ds.dI.tpos; % grab initial tpos
            app.mesh = ds.dI.mesh;
            app.init_atlas = app.mesh;
            app.pM = ds.dI.pM;
            app.paramsFoci = ds.dI.paramsFoci;
            app.Ns = ds.dI.Ns;
            app.Nd = length(app.tpos((app.Ns+1):end,1));
            app.pad = ds.dI.pad;
            app.tpos2 = zeros(size(app.tpos));
            
            if ~isfield(ds.dI, 'spheres')
                ds.dI.spheres = 1;

            end
            
            app.Switch.Value = ds.dI.spheres;
            
            if ~isfield(ds.dI, 'photoPath')
                app.photoPath = '';
            else
                app.photoPath = ds.dI.photoPath;
            end
            
            if ~isfield(ds.dI, 'imageType')
                app.imageType = '.png';
            else
                app.imageType = ds.dI.imageType;
            end


            app.atlasFiducials = ds.dI.atlasFiducials;
            
            
            % output structure
            ds.dO.in_values = [];
            ds.dO.out_values = [];
            ds.dO.in_tpos2 = zeros(size(app.tpos));         % preallocate array for initial optode positions
            ds.dO.out_tpos2 = zeros(size(app.tpos));        % preallocate array for manipulated optode positions
            ds.dO.tpos2_relaxed = zeros(size(app.tpos));    % preallocate array for relaxed optode positions

            % set fig handles
            app.pM.fig_handle = app.UIAxes;
            app.paramsFoci.fig_handle = app.UIAxes;
            app.ViewEditField.Value = 180;
            app.ViewEditField_2.Value = 0;
            
            % init arrays for plot figure handles
            app.plots.hh = zeros(size(app.tpos,1),1);
            app.plots.dots = [0];
            app.plots.ts = zeros(length(app.tpos(1:app.Ns,1)),1);
            app.plots.td = zeros(length(app.tpos((app.Ns+1):end,1)),1);
            
            % init undo button variables
            app.prev_state.values = [];
            app.prev_state.tpos2 = zeros(size(app.tpos));
            
            % do math for setting cap to behind the head button and add values to structure
            avg1 = mean(app.tpos,1);         % get center of mass of cap grid
            avg2 = mean(app.mesh.nodes,1);   % get center of mass of head mesh
            app.meshMax = max(abs(app.mesh.nodes),[],'all');
            
            % subtract mesh avg from cap grid avg and save dx,dy,dz values
            dr = -(avg1-avg2); % change in vector for new cap placement
            app.bH_values.dx = dr(1,1);
            app.bH_values.dy = dr(1,2);
            app.bH_values.dz = dr(1,3);
            
            % subtract 10 to get point behind head and update dy value
            newY = min(app.mesh.nodes(:,2)) - 10;
            app.bH_values.dy = app.bH_values.dy + newY;
            app.fH_values.dx = -dr(1,1);
            app.fH_values.dy = -dr(1,2);
            app.fH_values.dz = -dr(1,3);
            newY = max(app.mesh.nodes(:,2)) + 10;
            app.fH_values.dy = app.fH_values.dy + newY;
            
            % plot mesh
            PlotMeshSurface_220725(app.init_atlas,app.pM);
            view(app.UIAxes, [180,0]) % XZ view default

            % get starting cap manip values based in initial tpos
%             setToInitialCapPos(app);
            [app.values,app.tpos2] = getValues(app);
            app.ds.dO.in_values = app.values;
            app.ds.dO.in_tpos2 = app.tpos2;
            app.initCm = app.tpos2;
            app.init_values = app.tpos2;
            
            % draw cylinders with initial tpos
            app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
            
            if app.lim < app.meshMax
                app.lim = app.meshMax;
            end
            
            if app.lim < 110
                app.lim = 110;
            end
            app.defaultLim = app.lim;
            
            draw_foci(app,app.tpos2,app.paramsFoci)     
            
            if isempty(app.photoPath)
                app.Image.ImageSource = imread('defaultPhoto.png');
            else
                % Load in photos
                app.listOfImages = dir([app.photoPath,'/*', app.imageType]);
                app.photos = cell(1,length(app.listOfImages));
    
                for i=1:length(app.listOfImages)
                    app.photos{i}=imread(app.listOfImages(i).name);
                end
                
                % Show first image
                app.imageNumber = 1;
                app.Image.ImageSource = app.photos{app.imageNumber};
            end
            
            axis(app.UIAxes, 'padded')
            app.UIFigure.Visible = 'on';
            app.UIAxes.XColor = 'w';
            app.UIAxes.YColor = 'w';
            app.UIAxes.ZColor = 'w';    
            
            % set lighting
            light(app.UIAxes,'Position', [500 -500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 -500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [500 500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 500 500], 'Style', 'local');
            light(app.UIAxes,'Position', [500 500 500], 'Style', 'local');
            light(app.UIAxes,'Position', [500 -500 500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 -500 500], 'Style', 'local');
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];

        end

        % Button pushed function: RelaxArrayButton
        function RelaxArrayButtonPushed(app, event)
            app.relaxedVar = 1;
            [~, tpos] = getValues(app); % using local tpos var to avoid overwriting app.tpos


            %% Relax grid on head and view
            m0.nodes=app.mesh.nodes;
            m0.elements=app.mesh.elements;
            spos3=tpos(1:app.Ns,:);
            dpos3=tpos((app.Ns+1):end,:);
            tposNew=gridspringfit_ND2(m0,app.pad,spos3,dpos3);
            app.ds.dO.tpos2_relaxed = tposNew; % set relaxed SD positions to tpos_relaxed variable in ds.dO structure
            
            % reset prev state
            app.prev_state.values=[];
            app.prev_state.tpos2(:)=0;
                
            deletePlots(app)
            draw_foci(app,tposNew,app.paramsFoci)
            
            app.lim = ceil(max(abs(tposNew),[],'all'))+10;
            if app.lim < app.meshMax
                app.lim = app.meshMax;
            end
                    
            uialert(app.UIFigure,'Mesh is now relaxed','Success','Icon','success');
            axis(app.UIAxes, 'padded')
            app.relaxedVar = 0;
            light(app.UIAxes,'Position', [500 -500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 -500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [500 500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 500 500], 'Style', 'local');
            light(app.UIAxes,'Position', [500 500 500], 'Style', 'local');
            light(app.UIAxes,'Position', [500 -500 500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 -500 500], 'Style', 'local');
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
            
        end

        % Button pushed function: ScaleMeshButton
        function ScaleMeshButtonPushed(app, event)
            % Don't allow scaling to 0
            if app.HeadCircumferenceEditField.Value == 0 && app.NasiontoInionDistEditField.Value == 0 && app.LefttoRightPADistEditField.Value == 0
                msgbox('You cannot scale the atlas to 0!', 'ERROR', "error", "modal");
                
            % Don't allow scaling multiple times
            elseif app.MeshscaledCheckBox.Value == 1
                msgbox('Mesh has already been scaled.')
                
            else
                

                % Atlas parameters
                atlasFids = [app.atlasFiducials];
                distances = pdist(atlasFids);
                a = distances(1)/2;   % Nasion to inion
                b = distances(8)/2;   % LPA to RPA
                c = (a+b)/4;
                
                x0 = [a,b,c];
                
                % if C, Z, and HC provided
                if app.HeadCircumferenceEditField.Value ~= 0
                   % Participant-specific parameters
                   headCirc = app.HeadCircumferenceEditField.Value * 10;
                   
                   c = app.LefttoRightPADistEditField.Value ~= 0;
                   z = app.NasiontoInionDistEditField.Value ~= 0;
                   
                   switch true
                       case c==1 && z==1
                           cLine = app.LefttoRightPADistEditField.Value*10;
                           zLine = app.NasiontoInionDistEditField.Value*10;
                       case c==1 && z==0
                           cLine = app.LefttoRightPADistEditField.Value*10;
                           zLine = cLine;
                       case c==0 && z==1
                           zLine = app.NasiontoInionDistEditField.Value*10;
                           cLine = zLine;
                       case c==0 && z==0
                           zLine = headCirc/2;
                           cLine = zLine;
                   end
                   x = fminsearch(@(x) ellipsoidCostFunction(app,x,headCirc,cLine,...
                        zLine),x0);
                else
                    c = app.LefttoRightPADistEditField.Value ~= 0;
                    z = app.NasiontoInionDistEditField.Value ~= 0;
                    switch true
                        case c==1 && z==1
                           cLine = app.LefttoRightPADistEditField.Value*10;
                           zLine = app.NasiontoInionDistEditField.Value*10;
                       case c==1 && z==0
                           cLine = app.LefttoRightPADistEditField.Value*10;
                           zLine = cLine;
                       case c==0 && z==1
                           zLine = app.NasiontoInionDistEditField.Value*10;
                           cLine = zLine; 
                    end
                    headCirc = cLine + zLine;
                    x = fminsearch(@(x) ellipsoidCostFunction(app,x,headCirc,cLine,...
                        zLine),x0);
                end

                a=x(1); b=x(2); c=x(3);
                
                Cz = [0,0,c];
                Nz = [0,-a,0];
                Iz = [0,a,0];
                LPA = [-b,0,0];
                RPA = [b,0,0];
                subjFids = [Nz;Iz;LPA;RPA;Cz];
                
                atlasPts = [atlasFids, ones(size(atlasFids,1),1)];
                subjPts = [subjFids, ones(size(subjFids,1),1)];
%                 atlasPts = atlasFids; subjPts = subjFids;
                app.W = atlasPts\subjPts;
                app.ds.dO.affineTform = app.W;

                app.mesh.nodes = [app.mesh.nodes, ones(size(app.mesh.nodes,1),1)] * app.W;
                app.mesh.nodes = app.mesh.nodes(:,1:3);
                
                % Finished scaling procedure
                fprintf('Scaling finished \n');
                app.MeshscaledCheckBox.Value = 1;
                cla(app.UIAxes);
                PlotMeshSurface_220725(app.mesh, app.pM);
                draw_foci(app,app.tpos2,app.paramsFoci);
            end
            axis(app.UIAxes, 'padded')
            light(app.UIAxes,'Position', [500 -500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 -500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [500 500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 500 500], 'Style', 'local');
            light(app.UIAxes,'Position', [500 500 500], 'Style', 'local');
            light(app.UIAxes,'Position', [500 -500 500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 -500 500], 'Style', 'local');
            
            app.meshMax = ceil(max(abs(app.mesh.nodes),[],'all'))+10;
            if app.lim < app.meshMax
                app.lim = app.meshMax;
            end
            
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Button pushed function: XZ_ViewButton
        function XZ_ViewButtonPushed(app, event)
            view(app.UIAxes, [0,0])
            app.ViewEditField.Value = 0;
            app.ViewEditField_2.Value = 0;
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Button pushed function: XZ_ViewButton_2
        function XZ_ViewButton_2Pushed(app, event)
            view(app.UIAxes, [180,0])
            app.ViewEditField.Value = 180;
            app.ViewEditField_2.Value = 0;
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Button pushed function: XY_ViewButton
        function XY_ViewButtonPushed(app, event)
            view(app.UIAxes, [0,90])
            app.ViewEditField.Value = 0;
            app.ViewEditField_2.Value = 90;
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Button pushed function: XY_ViewButton_2
        function XY_ViewButton_2Pushed(app, event)
            view(app.UIAxes, [0,-90])
            app.ViewEditField.Value = 0;
            app.ViewEditField_2.Value = -90;
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Button pushed function: YZ_ViewButton
        function YZ_ViewButtonPushed(app, event)
            view(app.UIAxes, [90,0])
            app.ViewEditField.Value = 90;
            app.ViewEditField_2.Value = 0;
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Button pushed function: YZ_ViewButton_2
        function YZ_ViewButton_2Pushed(app, event)
            view(app.UIAxes, [-90,0])
            app.ViewEditField.Value = -90;
            app.ViewEditField_2.Value = 0;
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Button pushed function: UpdateViewButton
        function UpdateViewButtonPushed(app, event)
            view(app.UIAxes, [app.ViewEditField.Value,app.ViewEditField_2.Value])
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Value changed function: TurnOptodeLabelsOnButton
        function TurnOptodeLabelsOnButtonValueChanged(app, event)
            value = app.TurnOptodeLabelsOnButton.Value;
            
            if value == 1
                [~, tpos] = getValues(app); % using local tpos var to avoid overwriting app.tpos
                plotSD(app,tpos(1:app.Ns,:),tpos((app.Ns+1):end,:),'discs',app.UIAxes); % draws center justified numbers
                app.TurnOptodeLabelsOnButton.Text = "Turn Optode Labels Off";
            else
                [~, tpos] = getValues(app);
                delete(app.plots.ts);app.plots.ts(:)=0;
                delete(app.plots.td);app.plots.td(:)=0;
                app.TurnOptodeLabelsOnButton.Text = "Turn Optode Labels On";
            end
            axis(app.UIAxes, 'padded')
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Value changed function: ResetDropDown
        function ResetDropDownValueChanged(app, event)
            value = app.ResetDropDown.Value;
            
            switch value
                case "to Initial Position"
                    setToInitialCapPos(app);
                    app.prev_state.values = app.values;
                    app.prev_state.tpos2 = app.tpos2;
 
                    [app.values, app.tpos2] = getValues(app);
                    app.alltpos2 = app.tpos2;
                    app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
                    if app.lim < app.meshMax
                        app.lim = app.meshMax;
                    end
                    
                    deletePlots(app)
                    draw_foci(app,app.tpos2,app.paramsFoci)
                    axis(app.UIAxes, 'padded')

                    app.UIAxes.XLim = [-app.lim,app.lim];
                    app.UIAxes.YLim = [-app.lim,app.lim];
                    app.UIAxes.ZLim = [-app.lim,app.lim];
                    
                case "to Front of the Mesh"
                    setToInitialCapPos(app);
                    app.prev_state.values = app.values;
                    app.prev_state.tpos2 = app.tpos2;
 
                    [app.values, app.tpos2] = getValues(app);
                    app.alltpos2 = app.tpos2;
                    app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
                    if app.lim < app.meshMax
                        app.lim = app.meshMax;
                    end
                    
                    deletePlots(app)
                    draw_foci(app,app.tpos2,app.paramsFoci)
                    axis(app.UIAxes, 'padded')

                    app.UIAxes.XLim = [-app.lim,app.lim];
                    app.UIAxes.YLim = [-app.lim,app.lim];
                    app.UIAxes.ZLim = [-app.lim,app.lim];
                    
                case "to Behind the Mesh"
                    setToBehindTheHead(app);
                    app.prev_state.values = app.values;
                    app.prev_state.tpos2 = app.tpos2;

                    [app.values, app.tpos2] = getValues(app);
                    app.alltpos2 = app.tpos2;
                    app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
                    if app.lim < app.meshMax
                        app.lim = app.meshMax;
                    end
                    
                    deletePlots(app)
                    draw_foci(app,app.tpos2,app.paramsFoci)
                    axis(app.UIAxes, 'padded')
                    app.UIAxes.XLim = [-app.lim, app.lim];
                    app.UIAxes.YLim = [-app.lim, app.lim];
                    app.UIAxes.ZLim = [-app.lim, app.lim];
                    
                case "to Origin"
                    setToOrigin(app);
                    app.prev_state.values = app.values;
                    app.prev_state.tpos2 = app.tpos2;

                    [app.values, app.tpos2] = getValues(app);
                    app.alltpos2 = app.tpos2;
                    app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
                    if app.lim < app.meshMax
                        app.lim = app.meshMax;
                    end
                    
                    deletePlots(app)
                    draw_foci(app,app.tpos2,app.paramsFoci)
                    axis(app.UIAxes, 'padded')
                    app.UIAxes.XLim = [-app.lim, app.lim];
                    app.UIAxes.YLim = [-app.lim, app.lim];
                    app.UIAxes.ZLim = [-app.lim, app.lim];
            end
            

        end

        % Button pushed function: SaveAlignmentButton
        function SaveAlignmentButtonPushed(app, event)
            [values,tpos2] = getValues(app); % grab final values and tpos
            app.ds.dO.out_values = values; % save final values
            app.ds.dO.out_tpos2 = tpos2; % save final tpos
            
            %%% affine transform
            tpos_initial = app.ds.dI.tpos; % get starting tpos
            R = rotation_matrix('x',values.dxTh*pi/180)*rotation_matrix('y',values.dyTh*pi/180)*rotation_matrix('z',values.dzTh*pi/180);
            t = -R*mean(tpos_initial)'+[values.dx, values.dy, values.dz]' + mean(tpos_initial)';
            tformCap = [R t; 0 0 0 1];
            app.ds.dO.tformCap = tformCap;
            app.ds.dO.mesh = app.mesh;
            
            msgfig = msgbox('Alignment saved! The app will now close.','Success','modal');
            uiwait(msgfig)
            delete(app)
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.imageNumber = app.imageNumber + 1;
            if app.imageNumber > length(app.listOfImages)
                app.imageNumber = 1;
            end
            app.Image.ImageSource = app.photos{app.imageNumber};
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.imageNumber = app.imageNumber - 1;
            if app.imageNumber == 0
                app.imageNumber = length(app.listOfImages);
            end
            app.Image.ImageSource = app.photos{app.imageNumber};
        end

        % Value changed function: dxEditField
        function dxEditFieldValueChanged(app, event)
            
            app.prev_state.values = app.values;
            app.prev_state.tpos2 = app.tpos2;

            [app.values, app.tpos2] = getValues(app);
            app.alltpos2 = app.tpos2;
            app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
            if app.lim < app.meshMax
                app.lim = app.meshMax;
            end
            
            deletePlots(app)
            draw_foci(app,app.tpos2,app.paramsFoci)
            axis(app.UIAxes, 'padded')
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Value changed function: dyEditField
        function dyEditFieldValueChanged(app, event)
            
            app.prev_state.values = app.values;
            app.prev_state.tpos2 = app.tpos2;

            [app.values, app.tpos2] = getValues(app);
            app.alltpos2 = app.tpos2;
            app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
            if app.lim < app.meshMax
                app.lim = app.meshMax;
            end

            deletePlots(app)
            draw_foci(app,app.tpos2,app.paramsFoci)
            axis(app.UIAxes, 'padded')
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Value changed function: dzEditField
        function dzEditFieldValueChanged(app, event)
            
            app.prev_state.values = app.values;
            app.prev_state.tpos2 = app.tpos2;

            [app.values, app.tpos2] = getValues(app);
            app.alltpos2 = app.tpos2;
            app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
            if app.lim < app.meshMax
                app.lim = app.meshMax;
            end

            deletePlots(app)
            draw_foci(app,app.tpos2,app.paramsFoci)
            axis(app.UIAxes, 'padded')
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Value changed function: xrotEditField
        function xrotEditFieldValueChanged(app, event)
            
            app.prev_state.values = app.values;
            app.prev_state.tpos2 = app.tpos2;

            
            [app.values, app.tpos2] = getValues(app);
            app.alltpos2 = app.tpos2;
            app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
            if app.lim < app.meshMax
                app.lim = app.meshMax;
            end
            
            deletePlots(app)
            draw_foci(app,app.tpos2,app.paramsFoci)
            axis(app.UIAxes, 'padded')
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Value changed function: yrotEditField
        function yrotEditFieldValueChanged(app, event)
            
            app.prev_state.values = app.values;
            app.prev_state.tpos2 = app.tpos2;

            [app.values, app.tpos2] = getValues(app);
            app.alltpos2 = app.tpos2;
            app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
            if app.lim < app.meshMax
                app.lim = app.meshMax;
            end

            deletePlots(app)
            draw_foci(app,app.tpos2,app.paramsFoci)
            axis(app.UIAxes, 'padded')
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Value changed function: zrotEditField
        function zrotEditFieldValueChanged(app, event)
            
            app.prev_state.values = app.values;
            app.prev_state.tpos2 = app.tpos2;

            [app.values, app.tpos2] = getValues(app);
            app.alltpos2 = app.tpos2;
            app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
            if app.lim < app.meshMax
                app.lim = app.meshMax;
            end

            deletePlots(app)
            draw_foci(app,app.tpos2,app.paramsFoci)
            axis(app.UIAxes, 'padded')
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
            
            app.prev_state.values = app.values;
            app.prev_state.tpos2 = app.tpos2;

            [app.values, app.tpos2] = getValues(app);
            app.alltpos2 = app.tpos2;
            app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
            if app.lim < app.meshMax
                app.lim = app.meshMax;
            end
            
            deletePlots(app)
            draw_foci(app,app.tpos2,app.paramsFoci)
            axis(app.UIAxes, 'padded')
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Button pushed function: RotateCWButton
        function RotateCWButtonPushed(app, event)
            app.photos{app.imageNumber} = imrotate(app.photos{app.imageNumber}, -90, 'bilinear');
            app.Image.ImageSource = app.photos{app.imageNumber};
        end

        % Button pushed function: RotateCCWButton
        function RotateCCWButtonPushed(app, event)
            app.photos{app.imageNumber} = imrotate(app.photos{app.imageNumber}, 90, 'bilinear');
            app.Image.ImageSource = app.photos{app.imageNumber};
        end

        % Button pushed function: ResetMeshButton
        function ResetMeshButtonPushed(app, event)
            app.mesh = app.init_atlas;
            app.MeshscaledCheckBox.Value = 0;
            cla(app.UIAxes);
            app.meshMax = ceil(max(abs(app.mesh.nodes),[],'all'))+10;
            
            PlotMeshSurface_220725(app.mesh,app.pM);
            draw_foci(app,app.tpos2,app.paramsFoci);
            view(app.UIAxes, [180,0]) % XZ view default
            axis(app.UIAxes, 'padded')
            
            app.lim = app.defaultLim;
            light(app.UIAxes,'Position', [500 -500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 -500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [500 500 -500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 500 500], 'Style', 'local');
            light(app.UIAxes,'Position', [500 500 500], 'Style', 'local');
            light(app.UIAxes,'Position', [500 -500 500], 'Style', 'local');
            light(app.UIAxes,'Position', [-500 -500 500], 'Style', 'local');
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Value changed function: flipXCheckBox
        function flipXCheckBoxValueChanged(app, event)
            value = app.flipXCheckBox.Value;
            app.prev_state.values = app.values;
            app.prev_state.tpos2 = app.tpos2;

            [app.values, app.tpos2] = getValues(app);
            app.alltpos2 = app.tpos2;
            app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
            if app.lim < app.meshMax
                app.lim = app.meshMax;
            end

            deletePlots(app)
            draw_foci(app,app.tpos2,app.paramsFoci)
            axis(app.UIAxes, 'padded')
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Value changed function: flipYCheckBox
        function flipYCheckBoxValueChanged(app, event)
            value = app.flipYCheckBox.Value;
            app.prev_state.values = app.values;
            app.prev_state.tpos2 = app.tpos2;

            [app.values, app.tpos2] = getValues(app);
            app.alltpos2 = app.tpos2;
            app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
            if app.lim < app.meshMax
                app.lim = app.meshMax;
            end

            deletePlots(app)
            draw_foci(app,app.tpos2,app.paramsFoci)
            axis(app.UIAxes, 'padded')
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Value changed function: flipZCheckBox
        function flipZCheckBoxValueChanged(app, event)
            value = app.flipZCheckBox.Value;
            app.prev_state.values = app.values;
            app.prev_state.tpos2 = app.tpos2;
            app.lim = ceil(max(abs(app.tpos2),[],'all'))+10;
            if app.lim < app.meshMax
                app.lim = app.meshMax;
            end

            [app.values, app.tpos2] = getValues(app);
            app.alltpos2 = app.tpos2;

            deletePlots(app)
            draw_foci(app,app.tpos2,app.paramsFoci)
            axis(app.UIAxes, 'padded')
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            deletePlots(app)
            app.TurnOptodeLabelsOnButton.Value = 0;
            app.TurnOptodeLabelsOnButton.Text = "Turn Optode Labels On";
            draw_foci(app, app.tpos2, app.paramsFoci)
            axis(app.UIAxes, 'padded')
            app.UIAxes.XLim = [-app.lim,app.lim];
            app.UIAxes.YLim = [-app.lim,app.lim];
            app.UIAxes.ZLim = [-app.lim,app.lim];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AlignMeArrayManipulationAppLabel = uilabel(app.UIFigure);
            app.AlignMeArrayManipulationAppLabel.Text = 'AlignMe: Array Manipulation App';
            app.ArrayAlignmentPanel = uipanel(app.UIFigure);
            app.ArrayAlignmentPanel.Title = 'Array Alignment';
            app.dxEditFieldLabel = uilabel(app.ArrayAlignmentPanel);
            app.dxEditFieldLabel.Text = 'dx';
            app.dyEditFieldLabel = uilabel(app.ArrayAlignmentPanel);
            app.dyEditFieldLabel.Text = 'dy';
            app.dzEditFieldLabel = uilabel(app.ArrayAlignmentPanel);
            app.dzEditFieldLabel.Text = 'dz';
            app.ScaleEditFieldLabel = uilabel(app.ArrayAlignmentPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.xrotEditFieldLabel = uilabel(app.ArrayAlignmentPanel);
            app.xrotEditFieldLabel.Text = 'x rot.';
            app.yrotEditFieldLabel = uilabel(app.ArrayAlignmentPanel);
            app.yrotEditFieldLabel.Text = 'y rot.';
            app.zrotEditFieldLabel = uilabel(app.ArrayAlignmentPanel);
            app.zrotEditFieldLabel.Text = 'z rot.';
            app.ResetDropDownLabel = uilabel(app.ArrayAlignmentPanel);
            app.ResetDropDownLabel.Text = 'Reset:';
            app.ResetDropDown = uidropdown(app.ArrayAlignmentPanel);
            app.ResetDropDown.Items = {'to Initial Position', 'to Front of the Mesh', 'to Behind the Mesh', 'to Origin'};
            app.ResetDropDown.ValueChangedFcn = createCallbackFcn(app, @ResetDropDownValueChanged, true);
            app.ResetDropDown.Value = 'to Initial Position';
            app.TurnOptodeLabelsOnButton = uibutton(app.ArrayAlignmentPanel, 'state');
            app.TurnOptodeLabelsOnButton.ValueChangedFcn = createCallbackFcn(app, @TurnOptodeLabelsOnButtonValueChanged, true);
            app.TurnOptodeLabelsOnButton.Text = 'Turn Optode Labels On';
            app.RelaxArrayButton = uibutton(app.ArrayAlignmentPanel, 'push');
            app.RelaxArrayButton.ButtonPushedFcn = createCallbackFcn(app, @RelaxArrayButtonPushed, true);
            app.RelaxArrayButton.Text = {'Relax Array'; ''};
            app.SaveAlignmentButton = uibutton(app.ArrayAlignmentPanel, 'push');
            app.SaveAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAlignmentButtonPushed, true);
            app.SaveAlignmentButton.Text = 'Save Alignment';
            app.flipXCheckBox = uicheckbox(app.ArrayAlignmentPanel);
            app.flipXCheckBox.ValueChangedFcn = createCallbackFcn(app, @flipXCheckBoxValueChanged, true);
            app.flipXCheckBox.Text = 'flip X';
            app.flipYCheckBox = uicheckbox(app.ArrayAlignmentPanel);
            app.flipYCheckBox.ValueChangedFcn = createCallbackFcn(app, @flipYCheckBoxValueChanged, true);
            app.flipYCheckBox.Text = 'flip Y';
            app.flipZCheckBox = uicheckbox(app.ArrayAlignmentPanel);
            app.flipZCheckBox.ValueChangedFcn = createCallbackFcn(app, @flipZCheckBoxValueChanged, true);
            app.flipZCheckBox.Text = 'flip Z';
            app.PressEntertoupdateLabel = uilabel(app.ArrayAlignmentPanel);
            app.PressEntertoupdateLabel.Text = {'Press ''Enter'' to update'; ''};
            app.zrotEditField = uieditfield(app.ArrayAlignmentPanel, 'numeric');
            app.zrotEditField.ValueChangedFcn = createCallbackFcn(app, @zrotEditFieldValueChanged, true);
            app.dzEditField = uieditfield(app.ArrayAlignmentPanel, 'numeric');
            app.dzEditField.ValueChangedFcn = createCallbackFcn(app, @dzEditFieldValueChanged, true);
            app.yrotEditField = uieditfield(app.ArrayAlignmentPanel, 'numeric');
            app.yrotEditField.ValueChangedFcn = createCallbackFcn(app, @yrotEditFieldValueChanged, true);
            app.dyEditField = uieditfield(app.ArrayAlignmentPanel, 'numeric');
            app.dyEditField.ValueChangedFcn = createCallbackFcn(app, @dyEditFieldValueChanged, true);
            app.xrotEditField = uieditfield(app.ArrayAlignmentPanel, 'numeric');
            app.xrotEditField.ValueChangedFcn = createCallbackFcn(app, @xrotEditFieldValueChanged, true);
            app.dxEditField = uieditfield(app.ArrayAlignmentPanel, 'numeric');
            app.dxEditField.ValueChangedFcn = createCallbackFcn(app, @dxEditFieldValueChanged, true);
            app.ScaleEditField = uieditfield(app.ArrayAlignmentPanel, 'numeric');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ScaleEditField.Value = 1;
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Image = uiimage(app.UIFigure);
            app.XZ_ViewButton = uibutton(app.UIFigure, 'push');
            app.XZ_ViewButton.ButtonPushedFcn = createCallbackFcn(app, @XZ_ViewButtonPushed, true);
            app.XZ_ViewButton.Text = 'XZ View';
            app.XY_ViewButton = uibutton(app.UIFigure, 'push');
            app.XY_ViewButton.ButtonPushedFcn = createCallbackFcn(app, @XY_ViewButtonPushed, true);
            app.XY_ViewButton.Text = 'XY View';
            app.YZ_ViewButton = uibutton(app.UIFigure, 'push');
            app.YZ_ViewButton.ButtonPushedFcn = createCallbackFcn(app, @YZ_ViewButtonPushed, true);
            app.YZ_ViewButton.Text = 'YZ View';
            app.RotateCCWButton = uibutton(app.UIFigure, 'push');
            app.RotateCCWButton.ButtonPushedFcn = createCallbackFcn(app, @RotateCCWButtonPushed, true);
            app.RotateCCWButton.Text = 'Rotate CCW';
            app.RotateCWButton = uibutton(app.UIFigure, 'push');
            app.RotateCWButton.ButtonPushedFcn = createCallbackFcn(app, @RotateCWButtonPushed, true);
            app.RotateCWButton.Text = 'Rotate CW';
            app.AtlasScalingPanel = uipanel(app.UIFigure);
            app.AtlasScalingPanel.Title = 'Atlas Scaling';
            app.ScaleMeshButton = uibutton(app.AtlasScalingPanel, 'push');
            app.ScaleMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleMeshButtonPushed, true);
            app.ScaleMeshButton.Text = 'Scale Mesh';
            app.LefttoRightPADistEditFieldLabel = uilabel(app.AtlasScalingPanel);
            app.LefttoRightPADistEditFieldLabel.Text = 'Left to Right PA Dist.';
            app.HeadCircumferenceEditFieldLabel = uilabel(app.AtlasScalingPanel);
            app.HeadCircumferenceEditFieldLabel.Text = 'Head Circumference';
            app.ResetMeshButton = uibutton(app.AtlasScalingPanel, 'push');
            app.ResetMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMeshButtonPushed, true);
            app.ResetMeshButton.Text = {'Reset Mesh'; ''};
            app.MeshscaledCheckBox = uicheckbox(app.AtlasScalingPanel);
            app.MeshscaledCheckBox.Enable = 'off';
            app.MeshscaledCheckBox.Text = 'Mesh scaled';
            app.NasiontoInionDistEditField = uieditfield(app.AtlasScalingPanel, 'numeric');
            app.NasiontoInionDistEditField.Limits = [0 Inf];
            app.LefttoRightPADistEditField = uieditfield(app.AtlasScalingPanel, 'numeric');
            app.LefttoRightPADistEditField.Limits = [0 Inf];
            app.HeadCircumferenceEditField = uieditfield(app.AtlasScalingPanel, 'numeric');
            app.HeadCircumferenceEditField.Limits = [0 Inf];
            app.NasiontoInionDistEditFieldLabel = uilabel(app.AtlasScalingPanel);
            app.NasiontoInionDistEditFieldLabel.Text = 'Nasion to Inion Dist.';
            app.XZ_ViewButton_2 = uibutton(app.UIFigure, 'push');
            app.XZ_ViewButton_2.ButtonPushedFcn = createCallbackFcn(app, @XZ_ViewButton_2Pushed, true);
            app.XZ_ViewButton_2.Text = '-XZ View';
            app.XY_ViewButton_2 = uibutton(app.UIFigure, 'push');
            app.XY_ViewButton_2.ButtonPushedFcn = createCallbackFcn(app, @XY_ViewButton_2Pushed, true);
            app.XY_ViewButton_2.Text = '-XY View';
            app.YZ_ViewButton_2 = uibutton(app.UIFigure, 'push');
            app.YZ_ViewButton_2.ButtonPushedFcn = createCallbackFcn(app, @YZ_ViewButton_2Pushed, true);
            app.YZ_ViewButton_2.Text = '-YZ View';
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.Switch.Items = {'Cylinders', 'Spheres'};
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 0;
            app.ViewLabel = uilabel(app.UIFigure);
            app.ViewLabel.Text = 'View';
            app.ViewEditField = uieditfield(app.UIFigure, 'numeric');
            app.ViewEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.UpdateViewButton = uibutton(app.UIFigure, 'push');
            app.UpdateViewButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateViewButtonPushed, true);
            app.UpdateViewButton.Text = 'Update View';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AlignMe_2020b_dev(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end