classdef Hummingbird < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        HummingbirdCapManipulationLabel  matlab.ui.control.Label
        ModifyParametersPanel      matlab.ui.container.Panel
        dxEditFieldLabel           matlab.ui.control.Label
        dxEditField                matlab.ui.control.NumericEditField
        dyEditFieldLabel           matlab.ui.control.Label
        dyEditField                matlab.ui.control.NumericEditField
        dzEditFieldLabel           matlab.ui.control.Label
        dzEditField                matlab.ui.control.NumericEditField
        ScaleEditFieldLabel        matlab.ui.control.Label
        ScaleEditField             matlab.ui.control.NumericEditField
        dxThEditFieldLabel         matlab.ui.control.Label
        dxThEditField              matlab.ui.control.NumericEditField
        dyThEditFieldLabel         matlab.ui.control.Label
        dyThEditField              matlab.ui.control.NumericEditField
        dzThEditFieldLabel         matlab.ui.control.Label
        dzThEditField              matlab.ui.control.NumericEditField
        flipXEditFieldLabel        matlab.ui.control.Label
        flipXEditField             matlab.ui.control.NumericEditField
        flipYEditFieldLabel        matlab.ui.control.Label
        flipYEditField             matlab.ui.control.NumericEditField
        flipZEditFieldLabel        matlab.ui.control.Label
        flipZEditField             matlab.ui.control.NumericEditField
        ResetDropDownLabel         matlab.ui.control.Label
        ResetDropDown              matlab.ui.control.DropDown
        SourceRangeEditFieldLabel  matlab.ui.control.Label
        SrcFrom                    matlab.ui.control.NumericEditField
        SrcTo                      matlab.ui.control.NumericEditField
        FromLabel                  matlab.ui.control.Label
        ToLabel                    matlab.ui.control.Label
        DetectorRangeLabel         matlab.ui.control.Label
        DetFrom                    matlab.ui.control.NumericEditField
        DetTo                      matlab.ui.control.NumericEditField
        FromLabel_2                matlab.ui.control.Label
        ToLabel_2                  matlab.ui.control.Label
        allOptodesCheck            matlab.ui.control.CheckBox
        AllOptodesLabel            matlab.ui.control.Label
        UpdateFigureButton         matlab.ui.control.Button
        RelaxMeshButton            matlab.ui.control.Button
        XZ_ViewButton              matlab.ui.control.Button
        XY_ViewButton              matlab.ui.control.Button
        YZ_ViewButton              matlab.ui.control.Button
        ViewEditFieldLabel         matlab.ui.control.Label
        ViewEditField              matlab.ui.control.NumericEditField
        ViewEditField_2            matlab.ui.control.NumericEditField
        ViewSDLabelsButton         matlab.ui.control.StateButton
        UndoButton                 matlab.ui.control.Button
        HelpButton                 matlab.ui.control.Button
        UpdateviewButton           matlab.ui.control.Button
        FINALIZEButton             matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tpos    % SD positions, 
        mesh    % head mesh
        pM      % parameters for head mesh plotting
        Ns      % number of total sources
        Nd      % number of total detectors
        paramsFoci % paramaters for Draw Foci function
        rad     % cap info
        
        plots   % ui axis plot
        prev_state % params and tpos2 from prevous state
        values  % cap manipulation params
        tpos2   % updated SD positions, var is passed from startup to update and undo buttons only
        bH_values % cap manipulation params for translation to behind the head mesh
        alltpos2 % tpos2 values after all optodes are changed, used for getSubsetValues
    end
    
    properties (Access = public)
        ds      % DataStorage object
        dS_UI   % user input for scale for relaxation process 
    end
    
    methods (Access = private)
        
        function [values,tpos2] = getValues(app)
            values = [];
            
            values.dx = app.dxEditField.Value;
            values.dy = app.dyEditField.Value;
            values.dz = app.dzEditField.Value;
            values.Scale = app.ScaleEditField.Value;
            values.dxTh = app.dxThEditField.Value;
            values.dyTh = app.dyThEditField.Value;
            values.dzTh = app.dzThEditField.Value;
            values.flipX = app.flipXEditField.Value;
            values.flipY = app.flipYEditField.Value;
            values.flipZ = app.flipZEditField.Value;
            
            tpos2=rotate_cap(app.tpos, [values.dxTh, values.dyTh, values.dzTh]);
            tpos2(:,1)=tpos2(:,1)+values.dx;
            tpos2(:,2)=tpos2(:,2)+values.dy;
            tpos2(:,3)=tpos2(:,3)+values.dz;
            tpos2=scale_cap(tpos2, values.Scale);
            tpos2=flip_cap(tpos2, [values.flipX, values.flipY, values.flipZ]);
        end
        
        function [values,tpos2] = getSubsetValues(app)
            values = [];
            fromSrc = get(app.SrcFrom, 'Value');
            toSrc = get(app.SrcTo, 'Value');
            fromDet = get(app.DetFrom, 'Value')+app.Ns;
            toDet = get(app.DetTo, 'Value')+app.Ns;
            
            values.dx = app.dxEditField.Value;
            values.dy = app.dyEditField.Value;
            values.dz = app.dzEditField.Value;
            values.Scale = app.ScaleEditField.Value;
            values.dxTh = app.dxThEditField.Value;
            values.dyTh = app.dyThEditField.Value;
            values.dzTh = app.dzThEditField.Value;
            values.flipX = app.flipXEditField.Value;
            values.flipY = app.flipYEditField.Value;
            values.flipZ = app.flipZEditField.Value;
            
            tpos2 = app.alltpos2;
            
            tpos2(fromSrc:toSrc,:)=rotate_cap(tpos2(fromSrc:toSrc, :), [values.dxTh, values.dyTh, values.dzTh]);
            tpos2(fromDet:toDet,:)=rotate_cap(tpos2(fromDet:toDet, :), [values.dxTh, values.dyTh, values.dzTh]);
            
            tpos2(fromSrc:toSrc,1)=tpos2(fromSrc:toSrc,1)+values.dx;
            tpos2(fromDet:toDet,1)=tpos2(fromDet:toDet,1)+values.dx;
            
            tpos2(fromSrc:toSrc,2)=tpos2(fromSrc:toSrc,2)+values.dy;
            tpos2(fromDet:toDet,2)=tpos2(fromDet:toDet,2)+values.dy;
            
            tpos2(fromSrc:toSrc,3)=tpos2(fromSrc:toSrc,3)+values.dz;
            tpos2(fromDet:toDet,3)=tpos2(fromDet:toDet,3)+values.dz;
            
            tpos2(fromSrc:toSrc,:)=scale_cap(tpos2(fromSrc:toSrc,:), values.Scale);
            tpos2(fromDet:toDet,:)=scale_cap(tpos2(fromDet:toDet,:), values.Scale);
            
            tpos2(fromSrc:toSrc,:)=flip_cap(tpos2(fromSrc:toSrc,:), [values.flipX, values.flipY, values.flipZ]);
            tpos2(fromDet:toDet,:)=flip_cap(tpos2(fromDet:toDet,:), [values.flipX, values.flipY, values.flipZ]);
            
        end
        
        function setValues(app,values)
            app.dxEditField.Value=values.dx;
            app.dyEditField.Value=values.dy;
            app.dzEditField.Value=values.dz;
            app.ScaleEditField.Value=values.Scale;
            app.dxThEditField.Value=values.dxTh;
            app.dyThEditField.Value=values.dyTh;
            app.dzThEditField.Value=values.dzTh;
            app.flipXEditField.Value=values.flipX;
            app.flipYEditField.Value=values.flipY;
            app.flipZEditField.Value=values.flipZ;
        end
    
        function setToInitialCapPos(app)
            app.dxEditField.Value=0;
            app.dyEditField.Value=0;
            app.dzEditField.Value=0;
            app.ScaleEditField.Value=1;
            app.dxThEditField.Value=0;
            app.dyThEditField.Value=0;
            app.dzThEditField.Value=0;
            app.flipXEditField.Value=0;
            app.flipYEditField.Value=0;
            app.flipZEditField.Value=0;
        end
        
        function setToBehindTheHead(app)
            app.dxEditField.Value=app.bH_values.dx;
            app.dyEditField.Value=app.bH_values.dy;
            app.dzEditField.Value=app.bH_values.dz;
            app.ScaleEditField.Value=1;
            app.dxThEditField.Value=0;
            app.dyThEditField.Value=0;
            app.dzThEditField.Value=0;
            app.flipXEditField.Value=0;
            app.flipYEditField.Value=0;
            app.flipZEditField.Value=0;
        end
    
        function setToBasicCap(app)
            app.dxEditField.Value = 0;
            app.dyEditField.Value = -110;
            app.dzEditField.Value = -40;
            app.ScaleEditField.Value = 1.05;
            app.dxThEditField.Value = 90;
            app.dyThEditField.Value = -90;
            app.dzThEditField.Value = -180;
            app.flipXEditField.Value = 0;
            app.flipYEditField.Value = 0;
            app.flipZEditField.Value = 1;
        end

        function deletePlots(app)
            if all(ismember(app.plots.hh(:),0))
                % do nothing
            else
                delete(app.plots.hh(:));app.plots.hh(:)=0;  % delete grid from figure
            end
            if all(ismember(app.plots.dots(:),0))
                % do nothing
            else
                delete(app.plots.dots(:));app.plots.dots(:)=0;   % delete plotSD markers
            end
            if all(ismember(app.plots.ts(:),0)) && all(ismember(app.plots.td(:),0))
                % do nothing
            else
                delete(app.plots.ts(:));app.plots.ts(:)=0;  % delete sources text from figure
                delete(app.plots.td(:));app.plots.td(:)=0;  % delete detectors text from figure
            end
        end
        
        function draw_foci(app,foci,params)
            % This function draw spheres of a specified color and radius at the
            % location given by foci. Params fields include: color, faces
            % radius. Only foci is strictly required as color and radius each
            % have default values ([0,0,0], and 5, repectively).
            %   params.color are rows of RGB values contained within [0,1].
            %   params.lighting can be any of 'phong' (default),'flat'
            
            %% Parameters and Initialization
            if ~isfield(params,'faces'),   params.faces=10;end
            if ~isfield(params,'lighting'),params.lighting='phong';end
            if ~isfield(params,'AmbientStrength'),params.AmbientStrength=0.02;end
            
            Nfoci=size(foci,1); % foci is (Ns+Nd)x3
            fig_handle = params.fig_handle;
            
            if ~isfield(params,'color'),params.color=zeros(Nfoci,3);end % color is a (Ns+Nd)x3 arrays where color(1) = pink, color(2:Ns) = dark red, color(Ns+1) = light blue, color(Ns+2:end) = dark blue,
            if size(params.color,1)~=Nfoci,params.color=repmat(params.color,[Nfoci,1]);end
            if ~isfield(params,'radius'),params.radius=ones(Nfoci,1).*5;end
            if size(params.radius,1)~=Nfoci,params.radius=repmat(params.radius,[Nfoci,1]);end % (Ns+Nd)x1
            
            [x,y,z]=sphere(params.faces); %x, y, z are 11x11
            
            %% Draw the foci
            for j=1:Nfoci % iterating from 1:(Ns+Nd)
                rad=params.radius(j);
                fvc = surf2patch(rad*x+foci(j,1),rad*y+foci(j,2),rad*z+foci(j,3)); %1x1 struct of faces = 100x4 and vertices = 121x3, which update every iteration
                app.plots.hh(j)=patch(fig_handle, fvc,...
                    'EdgeColor',params.color(j,:),'FaceColor',params.color(j,:),...
                    'EdgeAlpha',0, 'FaceLighting',params.lighting,...
                    'AmbientStrength',params.AmbientStrength);
            end
            
            axis(fig_handle,'image')
        end
        
        function plotSD(app,spos,dpos,type,F)
            if ~exist('type','var'), type='text';end
            figure_handle = F;
            textColor = 'k';
            sColor = [1,0,0];
            dColor = [0,0,1];
            
            switch type
                case 'norm'
                    if size(spos,2)==2
                        app.plots.dots(1)=plot(figure_handle,spos(:,1),spos(:,2),'r*');
                        hold(figure_handle,'on')
                        for i=1:size(spos,1)
                            app.plots.ts(i)=text(figure_handle,spos(i,1),spos(i,2),num2str(i),'Color',textColor);
                        end
                        app.plots.dots(1)=plot(dpos(:,1),dpos(:,2),'bo');
                        for i=1:size(dpos,1)
                            app.plots.td(i)=text(figure_handle,dpos(i,1),dpos(i,2),num2str(i),'Color',textColor);
                        end
                    else
                        app.plots.dots(1)=plot3(figure_handle,spos(:,1),spos(:,2),spos(:,3),'r*');
                        hold(figure_handle,'on')
                        for i=1:size(spos,1)
                            app.plots.ts(i)=text(figure_handle,spos(i,1),spos(i,2),spos(i,3),num2str(i),'Color',textColor,...
                                'FontSize',20);
                        end
                        app.plots.dots(1)=plot3(figure_handle,dpos(:,1),dpos(:,2),dpos(:,3),'bo');
                        for i=1:size(dpos,1)
                            app.plots.td(i)=text(figure_handle,dpos(i,1),dpos(i,2),dpos(i,3),num2str(i),'Color',textColor,...
                                'FontSize',20);
                        end
                    end
                    
                case 'discs'
                    MarkerSize=10;
                    app.plots.dots(1)=plot(figure_handle,spos(:,1),spos(:,2),'or','MarkerEdgeColor','k','MarkerFaceColor',[1 0.5 0.5],...
                        'MarkerSize',MarkerSize);
                    hold on
                    app.plots.dots(1)=plot(figure_handle,dpos(:,1),dpos(:,2),'ob','MarkerEdgeColor','k','MarkerFaceColor',[0.5 0.5 1],...
                        'MarkerSize',MarkerSize);
                    
                %% removed case 'render'
                    
                case 'text'
                    TextSize=10;
                    if size(spos,2)==2
                        hold(figure_handle,'on')
                        for i=1:size(spos,1)
                            app.plots.ts(i)=text(figure_handle,spos(i,1),spos(i,2),num2str(i),'Color',[1 .75 .75],...
                                'HorizontalAlignment','center','FontSize',TextSize,'FontWeight','bold');
                        end
                        for i=1:size(dpos,1)
                            app.plots.td(i)=text(figure_handle,dpos(i,1),dpos(i,2),num2str(i),'Color',[.55 .55 1],...
                                'HorizontalAlignment','center','FontSize',TextSize,'FontWeight','bold');
                        end
                    else
                        hold(figure_handle,'on')
                        for i=1:size(spos,1)
                            app.plots.ts(i)=text(figure_handle,spos(i,1),spos(i,2),spos(i,3),num2str(i),'Color',sColor,...
                                'HorizontalAlignment','center'); 
                        end
                        for i=1:size(dpos,1)
                            app.plots.td(i)=text(figure_handle,dpos(i,1),dpos(i,2),dpos(i,3),num2str(i),'Color',dColor,...
                                'HorizontalAlignment','center');
                        end
                    end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ds)
            app.ds = ds;
            app.tpos=ds.dI.tpos; % grab initial tpos
            app.mesh = ds.dI.mesh;
            app.pM = ds.dI.pM;
            app.paramsFoci = ds.dI.paramsFoci;
            app.Ns = ds.dI.Ns;
            app.Nd = length(app.tpos((app.Ns+1):end,1));
            app.rad = ds.dI.rad;
            app.tpos2 = zeros(size(app.tpos));
            
            % output structure
            ds.dO.in_values = [];
            ds.dO.out_values = [];
            ds.dO.in_tpos2 = zeros(size(app.tpos));         % preallocate array for initial optode positions
            ds.dO.out_tpos2 = zeros(size(app.tpos));        % preallocate array for manipulated optode positions
            ds.dO.tpos2_relaxed = zeros(size(app.tpos));    % preallocate array for relaxed optode positions

            % set fig handles
            app.pM.fig_handle = app.UIAxes;
            app.paramsFoci.fig_handle = app.UIAxes;
            
            % init arrays for plot figure handles
            app.plots.hh = zeros(size(app.tpos,1),1);
            app.plots.dots = [0];
            app.plots.ts = zeros(length(app.tpos(1:app.Ns,1)),1);
            app.plots.td = zeros(length(app.tpos((app.Ns+1):end,1)),1);
            
            % init undo button variables
            app.prev_state.values = [];
            app.prev_state.tpos2 = zeros(size(app.tpos));
            
            % do math for setting cap to behind the head button and add values to structure
            avg1 = mean(app.tpos,1);         % get center of mass of cap grid
            avg2 = mean(app.mesh.nodes,1);   % get center of mass of head mesh
            
            % subtract mesh avg from cap grid avg and save dx,dy,dz values
            dr = -(avg1-avg2); % change in vector for new cap placement
            app.bH_values.dx = dr(1,1);
            app.bH_values.dy = dr(1,2);
            app.bH_values.dz = dr(1,3);
            % subtract 10 to get point behind head and update dy value
            newY = min(app.mesh.nodes(:,2))-10;
            app.bH_values.dy = app.bH_values.dy+newY;
            
            % plot mesh
            PlotMeshSurface_200507(app.mesh,app.pM);
            view(app.UIAxes, [0,0]) % XZ view default
            
            % get starting cap manip values based in initial tpos (all values set to 0, except scale which is set to 1)
            setToInitialCapPos(app);
            [app.values,app.tpos2] = getValues(app);
            app.ds.dO.in_values = app.values;
            app.ds.dO.in_tpos2 = app.tpos2;
            
            % draw spheres with initial tpos
            draw_foci(app,app.tpos2,app.paramsFoci)     
            
            % set optode range parameters for GUI 
            app.allOptodesCheck.Value = 1;
            app.SrcFrom.Limits = [1, app.Ns]; app.SrcFrom.Value = 1;
            app.SrcTo.Limits = [1, app.Ns]; app.SrcTo.Value = app.Ns;
            app.DetFrom.Limits = [1, app.Nd]; app.DetFrom.Value = 1;
            app.DetTo.Limits = [1, app.Nd]; app.DetTo.Value = app.Nd;
            
            if app.allOptodesCheck.Value == 1
                app.SrcFrom.Enable = 0;
                app.SrcTo.Enable = 0;
                app.DetFrom.Enable = 0;
                app.DetTo.Enable = 0;
            end
            
            app.UIAxes.XColor = 'w';
            app.UIAxes.YColor = 'w';
            app.UIAxes.ZColor = 'w';    
        end

        % Button pushed function: UpdateFigureButton
        function UpdateFigureButtonPushed(app, event)
            app.prev_state.values = app.values;
            app.prev_state.tpos2 = app.tpos2;

            state = get(app.allOptodesCheck, 'Value');
            if state == 1 
                [app.values, app.tpos2] = getValues(app);
                app.alltpos2 = app.tpos2;
            else
                [app.values, app.tpos2] = getSubsetValues(app);
            end
            
            deletePlots(app)
            draw_foci(app,app.tpos2,app.paramsFoci)
            
        end

        % Button pushed function: RelaxMeshButton
        function RelaxMeshButtonPushed(app, event)
            %% get user input on how much to scale relaxed mesh
            pop = pop1(app,app.values);
            uiwait(pop.UIFigure)
            
            [~, tpos] = getValues(app); % using local tpos var to avoid overwriting app.tpos
            %% Relax grid on head and view
            m0.nodes=app.mesh.nodes;
            m0.elements=app.mesh.elements;
            tpos=scale_cap(tpos,app.dS_UI); % another scale, just in case
            spos3=tpos(1:app.Ns,:);
            dpos3=tpos((app.Ns+1):end,:);
            tposNew=gridspringfit_ND2(m0,app.rad,spos3,dpos3);
            app.ds.dO.tpos2_relaxed = tposNew; % set relaxed SD positions to tpos_relaxed variable in ds.dO structure
            
            % reset prev state
            app.prev_state.values=[];
            app.prev_state.tpos2(:)=0;
                
%             view(app.UIAxes, [0,0])
            deletePlots(app)
            draw_foci(app,tposNew,app.paramsFoci)
            
            uialert(app.UIFigure,'Mesh is now relaxed','Success','Icon','success');
        end

        % Button pushed function: XZ_ViewButton
        function XZ_ViewButtonPushed(app, event)
            view(app.UIAxes, [0,0])
            app.ViewEditField.Value=0;
            app.ViewEditField_2.Value=0;
        end

        % Button pushed function: XY_ViewButton
        function XY_ViewButtonPushed(app, event)
            view(app.UIAxes, [0,90])
            app.ViewEditField.Value=0;
            app.ViewEditField_2.Value=90;
        end

        % Button pushed function: YZ_ViewButton
        function YZ_ViewButtonPushed(app, event)
            view(app.UIAxes, [90,0])
            app.ViewEditField.Value=90;
            app.ViewEditField_2.Value=0;
        end

        % Value changed function: ViewSDLabelsButton
        function ViewSDLabelsButtonValueChanged(app, event)
            value = app.ViewSDLabelsButton.Value;
            
            if value == 1
                [~, tpos] = getValues(app); % using local tpos var to avoid overwriting app.tpos
                deletePlots(app)
                plotSD(app,tpos(1:app.Ns,:),tpos((app.Ns+1):end,:),'text',app.UIAxes); % draws center justified numbers
                app.ViewSDLabelsButton.Text = "View SD Spheres";
            else
                [~, tpos] = getValues(app);
                deletePlots(app)
                draw_foci(app,tpos,app.paramsFoci)
                app.ViewSDLabelsButton.Text = "View SD Labels";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            [values,tpos2] = getValues(app); % grab final values and tpos
            app.ds.dO.out_values = values; % save final values
            app.ds.dO.out_tpos2 = tpos2; % save final tpos
            
            %%% affine transform
            tpos_initial = app.ds.dI.tpos; % get starting tpos
            R = rotation_matrix('x',values.dxTh*pi/180)*rotation_matrix('y',values.dyTh*pi/180)*rotation_matrix('z',values.dzTh*pi/180);
            t = -R*mean(tpos_initial)'+[values.dx, values.dy, values.dz]' + mean(tpos_initial)';
            tformCap = [R t; 0 0 0 1];
            app.ds.dO.tformCap = tformCap;
            
            delete(app)
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if ~isempty(app.prev_state. values) || all(app.prev_state.tpos2(:)~=0) % if we have a prev state
                % revert to previous state
                setValues(app,app.prev_state.values);
                [app.values,app.tpos2]=getValues(app);  
                view(app.UIAxes,[0,0])
                deletePlots(app)
                draw_foci(app,app.tpos2,app.paramsFoci)
                
                % reset prev state
                app.prev_state.values=[];
                app.prev_state.tpos2(:)=0;
            else
                message = sprintf('Cannot Undo! \n Note: You are either trying to undo more than once OR the Figure is not up to date OR the mesh is relaxed. Try hitting "Update Figure"');
                uialert(app.UIFigure,message, 'Error')
            end
        end

        % Value changed function: ResetDropDown
        function ResetDropDownValueChanged(app, event)
            value = app.ResetDropDown.Value;
            
            switch value
                case "to Initial Cap Position"
                    setToInitialCapPos(app);
                    app.UpdateFigureButtonPushed;
                    
                case "to Behind the Head"
                    setToBehindTheHead(app);
                    app.UpdateFigureButtonPushed;
                    
                case "to Basic Cap Design"
                    setToBasicCap(app);
                    app.UpdateFigureButtonPushed; 
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            helpHummingbird;
        end

        % Button pushed function: UpdateviewButton
        function UpdateviewButtonPushed(app, event)
            view1 = app.ViewEditField.Value;
            view2 = app.ViewEditField_2.Value;
            view(app.UIAxes,[view1,view2])
        end

        % Button pushed function: FINALIZEButton
        function FINALIZEButtonPushed(app, event)
            app.UIFigureCloseRequest;
        end

        % Value changed function: allOptodesCheck
        function allOptodesCheckValueChanged(app, event)
            value = app.allOptodesCheck.Value;
            
            if value == 1
                app.SrcFrom.Enable = 0;
                app.SrcTo.Enable = 0;
                app.DetFrom.Enable = 0;
                app.DetTo.Enable = 0;
            else
                app.SrcFrom.Enable = 1;
                app.SrcTo.Enable = 1;
                app.DetFrom.Enable = 1;
                app.DetTo.Enable = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HummingbirdCapManipulationLabel = uilabel(app.UIFigure);
            app.HummingbirdCapManipulationLabel.Text = {'Hummingbird: '; 'Cap Manipulation'};
            app.ModifyParametersPanel = uipanel(app.UIFigure);
            app.ModifyParametersPanel.Title = 'Modify Parameters';
            app.dxEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.dxEditFieldLabel.Text = 'dx';
            app.dxEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.dyEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.dyEditFieldLabel.Text = 'dy';
            app.dyEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.dzEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.dzEditFieldLabel.Text = 'dz';
            app.dzEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.ScaleEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.ScaleEditField.Value = 1;
            app.dxThEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.dxThEditFieldLabel.Text = 'dxTh';
            app.dxThEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.dyThEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.dyThEditFieldLabel.Text = 'dyTh';
            app.dyThEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.dzThEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.dzThEditFieldLabel.Text = 'dzTh';
            app.dzThEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.flipXEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.flipXEditFieldLabel.Text = 'flipX';
            app.flipXEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.flipYEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.flipYEditFieldLabel.Text = 'flipY';
            app.flipYEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.flipZEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.flipZEditFieldLabel.Text = 'flipZ';
            app.flipZEditField = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.ResetDropDownLabel = uilabel(app.ModifyParametersPanel);
            app.ResetDropDownLabel.Text = 'Reset:';
            app.ResetDropDown = uidropdown(app.ModifyParametersPanel);
            app.ResetDropDown.Items = {'to Initial Cap Position', 'to Behind the Head', 'to Basic Cap Design'};
            app.ResetDropDown.ValueChangedFcn = createCallbackFcn(app, @ResetDropDownValueChanged, true);
            app.ResetDropDown.Value = 'to Initial Cap Position';
            app.SourceRangeEditFieldLabel = uilabel(app.ModifyParametersPanel);
            app.SourceRangeEditFieldLabel.Text = {'Source'; ' Range'};
            app.SrcFrom = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.SrcFrom.Limits = [1 Inf];
            app.SrcFrom.Value = 1;
            app.SrcTo = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.SrcTo.Limits = [1 Inf];
            app.SrcTo.Value = 1;
            app.FromLabel = uilabel(app.ModifyParametersPanel);
            app.FromLabel.Text = 'From:';
            app.ToLabel = uilabel(app.ModifyParametersPanel);
            app.ToLabel.Text = 'To:';
            app.DetectorRangeLabel = uilabel(app.ModifyParametersPanel);
            app.DetectorRangeLabel.Text = {'Detector'; ' Range'};
            app.DetFrom = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.DetFrom.Limits = [1 Inf];
            app.DetFrom.Value = 1;
            app.DetTo = uieditfield(app.ModifyParametersPanel, 'numeric');
            app.DetTo.Limits = [1 Inf];
            app.DetTo.Value = 1;
            app.FromLabel_2 = uilabel(app.ModifyParametersPanel);
            app.FromLabel_2.Text = 'From:';
            app.ToLabel_2 = uilabel(app.ModifyParametersPanel);
            app.ToLabel_2.Text = 'To:';
            app.allOptodesCheck = uicheckbox(app.ModifyParametersPanel);
            app.allOptodesCheck.ValueChangedFcn = createCallbackFcn(app, @allOptodesCheckValueChanged, true);
            app.allOptodesCheck.Text = '';
            app.allOptodesCheck.Value = true;
            app.AllOptodesLabel = uilabel(app.ModifyParametersPanel);
            app.AllOptodesLabel.Text = 'All Optodes';
            app.UpdateFigureButton = uibutton(app.UIFigure, 'push');
            app.UpdateFigureButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateFigureButtonPushed, true);
            app.UpdateFigureButton.Text = 'Update Figure';
            app.RelaxMeshButton = uibutton(app.UIFigure, 'push');
            app.RelaxMeshButton.ButtonPushedFcn = createCallbackFcn(app, @RelaxMeshButtonPushed, true);
            app.RelaxMeshButton.Text = 'Relax Mesh';
            app.XZ_ViewButton = uibutton(app.UIFigure, 'push');
            app.XZ_ViewButton.ButtonPushedFcn = createCallbackFcn(app, @XZ_ViewButtonPushed, true);
            app.XZ_ViewButton.Text = 'XZ View';
            app.XY_ViewButton = uibutton(app.UIFigure, 'push');
            app.XY_ViewButton.ButtonPushedFcn = createCallbackFcn(app, @XY_ViewButtonPushed, true);
            app.XY_ViewButton.Text = 'XY View';
            app.YZ_ViewButton = uibutton(app.UIFigure, 'push');
            app.YZ_ViewButton.ButtonPushedFcn = createCallbackFcn(app, @YZ_ViewButtonPushed, true);
            app.YZ_ViewButton.Text = 'YZ View';
            app.ViewEditFieldLabel = uilabel(app.UIFigure);
            app.ViewEditFieldLabel.Text = 'View';
            app.ViewEditField = uieditfield(app.UIFigure, 'numeric');
            app.ViewEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ViewSDLabelsButton = uibutton(app.UIFigure, 'state');
            app.ViewSDLabelsButton.ValueChangedFcn = createCallbackFcn(app, @ViewSDLabelsButtonValueChanged, true);
            app.ViewSDLabelsButton.Text = 'View SD Labels';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.UpdateviewButton = uibutton(app.UIFigure, 'push');
            app.UpdateviewButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateviewButtonPushed, true);
            app.UpdateviewButton.Text = 'Update view';
            app.FINALIZEButton = uibutton(app.UIFigure, 'push');
            app.FINALIZEButton.ButtonPushedFcn = createCallbackFcn(app, @FINALIZEButtonPushed, true);
            app.FINALIZEButton.Text = 'FINALIZE';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hummingbird(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end