classdef neurodot_render_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuroDOTRenderToolUIFigure  matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        MeshEditFieldLabel          matlab.ui.control.Label
        MeshEditField               matlab.ui.control.EditField
        InterpolationDropDownLabel  matlab.ui.control.Label
        InterpolationDropDown       matlab.ui.control.DropDown
        InflationDropDownLabel      matlab.ui.control.Label
        InflationDropDown           matlab.ui.control.DropDown
        ViewDropDown                matlab.ui.control.DropDown
        ViewDropDownLabel           matlab.ui.control.Label
        ColormapDropDown            matlab.ui.control.DropDown
        ColormapDropDownLabel       matlab.ui.control.Label
        ColorbarDropDown            matlab.ui.control.DropDown
        ColorbarDropDownLabel       matlab.ui.control.Label
        ColorscaleEditField         matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        ColorscaleSlider            matlab.ui.control.Slider
        ColorscaleSliderLabel       matlab.ui.control.Label
        OverlayEditField            matlab.ui.control.EditField
        OverlayEditFieldLabel       matlab.ui.control.Label
        QuitButton                  matlab.ui.control.Button
        FrameLabel                  matlab.ui.control.Label
        FrameSpinner                matlab.ui.control.Spinner
        SelectnewButton             matlab.ui.control.Button
        divider_01                  matlab.ui.control.Label
        colorscaleEditFieldLabel    matlab.ui.control.Label
        ButtonGroup                 matlab.ui.container.ButtonGroup
        xmaxoverlayButton           matlab.ui.control.RadioButton
        absoluteButton              matlab.ui.control.RadioButton
        ThresholdLabel              matlab.ui.control.Label
        NThreshSlider               matlab.ui.control.Slider
        PThreshSlider               matlab.ui.control.Slider
        NThreshEditField            matlab.ui.control.NumericEditField
        PThreshEditField            matlab.ui.control.NumericEditField
        minus_thresh_label          matlab.ui.control.Label
        plus_thresh_label           matlab.ui.control.Label
        divider_02                  matlab.ui.control.Label
        PDCheckbox                  matlab.ui.control.CheckBox
        TemplateSelectButton        matlab.ui.control.Button
        divider_04                  matlab.ui.control.Label
        PrintButton                 matlab.ui.control.Button
        RestoreDefaultsButton       matlab.ui.control.Button
        HelpButton                  matlab.ui.control.Button
    end

    
properties (Access = private)
    
     overlay
     overlay_header
     MESH_L
     MESH_R
     frame
     params
     helpwindow
     overlay_max
     
end
    
    
methods (Access = private)
 
%--------------------------------------------------------------------
 function setdefaultparams(app)
%--------------------------------------------------------------------
             
    % set (or reset) params to sensible defaults
    % and adjust GUI to match where necessary
    
    % this function called 1) on start up (from constructor)
    % and 2) from "reset defaults" button
                            
    app.params.Scale = 0.5;                 % good default for relative scaling
    app.params.Th.P = 0;
    app.params.Th.N = 0;
    app.params.Cmap = 'jet';
    app.params.view = 'lat';
    app.params.CBar_on = 1;
    app.params.PD = 0;
    app.params.ctx = 'std'; % std, inf, vinf
    app.params.OL = 0;
    app.params.render_tool = 1;
                
    % SCALING
    
    app.ColorscaleEditField.Value = app.params.Scale;
    app.ColorscaleSlider.Limits = [0 2*app.params.Scale];
    % set the slider value AFTER setting limits (new value may be > old limits)
    app.ColorscaleSlider.Value = app.params.Scale;

    %  abs/maxoverlay radio button
    % (also turns off yoked button(s))

    app.absoluteButton.Value = 0;           % relative scaling works better for startup
    app.xmaxoverlayButton.Value = 1;

    % THRESHOLDING
    
    app.PThreshEditField.Value = 0;
    app.PThreshSlider.Value = 0;
    
    % we process the negative threshold slider in the
    % callback so it behaves like it goes 100 (L) to 0 (R)
    
    app.NThreshEditField.Value = 0;
    app.NThreshSlider.Value = 100; 
    
    % GUI - MENUS
    
    app.ColorbarDropDown.Value = 'on';
    app.ColormapDropDown.Value = 'jet';
    app.ViewDropDown.Value = 'lat';
    app.InterpolationDropDown.Value = 'linear';
    app.PDCheckbox.Value = 0;
    
    % CORTICAL MESH
                 
    try
        temp = load('MNI164k_big.mat', 'MNIl', 'MNIr');
    catch
        delete(app); % no cortical mesh = fatal...
        error('Cannot load default cortical mesh (MNI164k_big.mat). Exiting...');
    end
    
    app.MESH_L = temp.MNIl;
    app.MESH_R = temp.MNIr;
    
    clear temp;

    app.MeshEditField.Value = 'MNI164k_big.mat';      
     
    % the default template includes inflated versions

    app.InflationDropDown.Enable = 'on';
    app.InflationDropDown.Value = 'none';
    
    % note no render() call here -- let caller decide

end
     
   
 %--------------------------------------------------------------------
 function toggle_multiframe_select(app,maxframes)
 %-------------------------------------------------------------------- 
 
    % toggle multifframe selection on/off

    if (maxframes > 1)
         s = sprintf('Frame /%03d',maxframes);
         app.FrameLabel.Text = s;
         app.FrameSpinner.Value = app.frame; 
         app.FrameSpinner.Limits = [ 1 maxframes ];
         app.FrameLabel.Visible = 'on';              
         app.FrameSpinner.Visible = 'on';
    else
         app.FrameLabel.Visible = 'off';              
         app.FrameSpinner.Visible = 'off';              
    end        

 end


%--------------------------------------------------------------------
 function render(app)
%--------------------------------------------------------------------
 
    % render the overlay using current settings
    
    if isempty(app.overlay); return; end
    
    PlotInterpSurfMesh(app.overlay(:,:,:,app.frame), app.MESH_L, app.MESH_R, app.overlay_header, app.params);           
    
    set(gcf,'Name','renderwin');
    set(gcf,'ToolBar','none');
    set(gcf,'NumberTitle','off');
    app.params.fig_handle = gcf;
    
end
 
        
%--------------------------------------------------------------------     
 function loadfile(app,pathname,filename)
%--------------------------------------------------------------------
          
    [~,n,e] = fileparts(filename);
    
    switch e
        
        case '.nii'
         
            [ temp_overlay,temp_header ] = LoadVolumetricData(fullfile(pathname,n),'','nii');
                
        case { '.ifh' , '.img' }
      
            [~,n,~] = fileparts(n); % strip extra suffix (e.g., wawa.4dfp.ifh => wawa.4dfp)
            [ temp_overlay,temp_header ] = LoadVolumetricData(fullfile(pathname,n),'','4dfp');
     
        case '.mat'
            
            temp = load(fullfile(pathname,filename));     
      
            if ~isfield(temp,'cortex_Hb')...
                || ~isfield(temp,'info')...
                || ~isfield(temp.info,'tissue') ...
                || ~isfield(temp.info.tissue,'dim')...
                    || ~isfield(temp,'flags')...
                    || ~isfield(temp.flags,'affine_target')...
                    || ~isfield(temp.flags,'affine')

            s1 = 'Invalid .mat file. Must contain cortex-Hb, info.tissue.dim, and flags structs';
            s2 = sprintf('Not a NeuroDOT file: %s',filename);
            uialert(app.NeuroDOTRenderToolUIFigure,s1,s2);            
            return;

            end
                    
            % cortex_Hb(:,:,1) == Hb0
            temp_overlay = Good_Vox2vol(squeeze(temp.cortex_Hb(:,:,1)), temp.info.tissue.dim);
            temp_overlay = affine3d_img(temp_overlay, temp.info.tissue.dim, temp.flags.affine_target, temp.flags.affine);
            temp_header = temp.info.tissue.dim;
            
        otherwise
             
            s1 = 'Invalid overlay. Must be nifti (.nii), 4dfp (.ifh/.img pair), or a NeuroDOT .mat file (filename must include extension)';
            s2 = sprintf('Bad overlay: %s', filename);
            uialert(app.NeuroDOTRenderToolUIFigure,s1,s2);            
            return;
    
    end
     
    app.overlay = temp_overlay;
    app.overlay_header = temp_header;
    
    clear temp_overlay;
    clear temp_header;
    
    app.update_gui_and_render(filename);
    
 end


%--------------------------------------------------------------------     
 function update_gui_and_render(app,filename)
%--------------------------------------------------------------------

    % update GUI and scaling settings to reflect the current
    % overlay, and then render the overlay
    
    % if overlay is 4D, display the frame selection spinner
    
    if (length(size(app.overlay)) > 3)
        nframes = size(app.overlay,4);
    else
        nframes = 1;
    end
               
    app.toggle_multiframe_select(nframes);
    app.frame = 1;
    
    % calculate & store overlay max (needed for relative scaling)
    
    temp = app.overlay(:,:,:,app.frame);
    app.overlay_max = max(temp(:));
    
    % make sure internal scaling is current
    
    if (app.absoluteButton.Value == 1)
        app.params.Scale = app.ColorscaleSlider.Value;
    else                
        app.params.Scale = app.ColorscaleSlider.Value * app.overlay_max;
    end 
    
    % re-calculate internal threshold fields so we're sure
    % they're consistent with current value of .Scale
    
    % the internal P threshold is proportion of internal Scale
    app.params.Th.P = app.PThreshEditField.Value/100 * app.params.Scale;
    % the internal N threshold is negative proportion of internal Scale
    app.params.Th.N = -abs(app.NThreshEditField.Value)/100 * app.params.Scale;
    
    % display the current overlay name in the overlay textfield
    
    app.OverlayEditField.Value = filename;
    
    % render!
    
    app.render();

end


%--------------------------------------------------------------------     
 function update_mesh_gui(app,meshname)
%--------------------------------------------------------------------
     
    % update mesh-related GUI elements to reflect current mesh 
                  
    app.MeshEditField.Value = meshname;
   
    % hide inflation menu if the new mesh is not inflation-capable
    
    if (~isfield(app.MESH_R,'Inodes') || ~isfield(app.MESH_R,'VInodes'))
       app.InflationDropDown.Enable = 'off';
       app.InflationDropDown.Value = 'none';
       app.params.ctx = 'std';
    else
       app.InflationDropDown.Enable = 'on';
    end  
    
 end


 %--------------------------------------------------------------------     
  function flag = is_settings_struct(~,s)
 %--------------------------------------------------------------------  
 
    % determine if the passed struct s is a valid initial settings
    % struct. This is a bit challenging because we don't require
    % any specific fields (e.g., 'colormap') to be present
    %
    % Instead, we test for content that identifies s as other data
    
    flag = false;
    
    if ~isstruct(s); return; end
        
    % is S a header? 
    if isfield(s,'nDim'); return; end
    
    % is s a cortical mesh struct?       
    if isfield(s,'nodes'); return; end
 
    flag = true;
    
 end

    
%--------------------------------------------------------------------     
 function apply_settings(app,s)
%--------------------------------------------------------------------

   % apply the display/render settings in s, overriding current values
   
   fn = fieldnames(s);
   
   % we could just loop over sfields and apply using
   %
   %    app.params.(fn{index}) = s.(fn{index})
   %
   % but that allows the possibility of undefined
   % or harmful settings due to typos etc. Instead,
   % we process a set of documented setting names
   % (see help) and attempt some sanity checking...
 
   for index=1:numel(fn)
       
       % we accept the internal NeuroDOT params struct as well as
       % a settings struct that uses more user-friendly fieldnames
       % you can even mix and match, although if a combination of
       % incompatiable settings is passed, the result is undefined
       
       % note the fieldnames are case-sensitive, although the user-
       % friendly fieldname are all lowercase (the internal param
       % stuct names are a mix)
       
       % there is also less error checking possible when passing
       % internal ND params -- we assume you know what you're doing
       
       fieldname = fn{index};
       equivalent_fieldname = fieldname;
       
       % we can reuse code by mapping some ND internal settings
       % onto their user-friendly setting name equivalents...
       
       if strcmp(fieldname,'CBar_on');  equivalent_fieldname = 'show_colorbar';       end
       if strcmp(fieldname,'view');     equivalent_fieldname = 'view';                end
       if strcmp(fieldname,'Cmap');     equivalent_fieldname = 'colormap';            end
       if strcmp(fieldname,'PD');       equivalent_fieldname = 'plot_positive_only';  end
       
       switch equivalent_fieldname
           
           % === there's a few internal ND params fields that don't map to new settings ===
  
           case 'Scale' % note uppercase "Scale"
               
                app.params.Scale = s.Scale;
                app.ColorscaleEditField.Value = app.params.Scale;
                app.ColorscaleSlider.Value = app.params.Scale;
                app.ColorscaleSlider.Limits = [0 2*app.params.Scale];
                
                % using the Scale field of the ND struct implies absolute scaling mode

                app.absoluteButton.Value = 1;
                app.xmaxoverlayButton.Value = 0;

                % if we change the scale we need to adjust internal thresholds 
                
                app.params.Th.P = app.PThreshEditField.Value/100 * app.params.Scale;
                app.params.Th.N = -app.NThreshEditField.Value/100 * app.params.Scale;
                       
           case 'Th'
                 
                % Th.P and Th.N are used internally verbatim
                % however, their GUI widgets display percent of Scale
                % so adjust accordingly. Also remember the negative
                % slider is flipped horizontally...
                
                % a potential problem is if a specified threshold exceeds the current
                % .Scale (i.e., because .Th appears in the struct *before* .Scale and
                % so Scale is still set to a potentially problematic default value). 
                % So update .Scale now (this is harmless if Scale was already set
                % or will be subsequently).
                %
                % If the user passed thresholds but not .Scale, we assume they
                % know what they're doing...

                if isfield(s,'Scale'); app.params.Scale = s.Scale ; end

                if isfield(s.Th,'P')
                    app.params.Th.P = s.Th.P;
                    app.PThreshEditField.Value = round(100*s.Th.P/app.params.Scale);
                    app.PThreshSlider.Value = app.PThreshEditField.Value;
                end
             
                if isfield(s.Th,'N')
                    app.params.Th.N = s.Th.N;
                    app.NThreshEditField.Value = round(100*abs(s.Th.N)/app.params.Scale);
                    app.NThreshSlider.Value = 100-app.NThreshEditField.Value;
                end
          
           case 'OL'
               
                app.params.OL = s.OL;
      
                if (s.OL == false)
                    app.InterpolationDropDown.Value = 'linear';
                else 
                    app.InterpolationDropDown.Value = 'nearest';
                end                
                
           case 'ctx'
               
                app.params.ctx = s.ctx; 

                switch s.ctx
                    
                    case 'std'
                        app.InflationDropDown.Value = 'none';
                    case 'inf'
                        app.InflationDropDown.Value = 'some';
                    case 'vinf'
                        app.InflationDropDown.Value = 'max';
                    
                end
  
           
           % === NEW USER-FRIENDLY SETTING FIELDNAMES ===
           
            case 'color_scale'
                             
                app.params.Scale = s.(fieldname);
                app.ColorscaleEditField.Value = app.params.Scale;
                app.ColorscaleSlider.Value = app.params.Scale;
                app.ColorscaleSlider.Limits = [0 2*app.params.Scale];

           case 'color_scale_mode'
               
                % scale will be appled in app.render()
                % just need to set flag here
               
                switch s.color_scale_mode
                
                    case 'absolute'
                        app.absoluteButton.Value = 1;
                        app.xmaxoverlayButton.Value = 0;
                    case 'relative'
                        app.absoluteButton.Value = 0;
                        app.xmaxoverlayButton.Value = 1;   
                        
                 end   
    
           case 'positive_threshold_percent'
                
                % note: just update GUI here -- Th.P and Th.N get set in update_gui_and_render
                % (bc we need the max(overlay) if doing relative color scale mode...)
                app.PThreshEditField.Value = s.positive_threshold_percent;
                app.PThreshSlider.Value = s.positive_threshold_percent;
             
           case 'negative_threshold_percent'
               
                % use abs() in case user mistakenly used a negative value here
                app.NThreshEditField.Value = abs(s.negative_threshold_percent);
                % the -ive slider goes 0-100; subtract from 100 to give appearance of -100-0
                app.NThreshSlider.Value = 100 - abs(s.negative_threshold_percent);           
               
           case 'cortex_inflation'
                         
              if ismember(s.cortex_inflation,app.InflationDropDown.Items)
                  
                  % note default mesh includes inflated variants so all
                  % options are valid on startup -- if user subsequently
                  % loads (or passes) a mesh that does not include inflated 
                  % variants, the inflation menu will be disabled
                      
                  switch s.cortex_inflation
                      
                    case 'none'
                        app.params.ctx = 'std';
                    case 'some'
                        app.params.ctx = 'inf';
                    case 'max'
                        app.params.ctx = 'vinf';
                   
                  end
                  
                  app.InflationDropDown.Value = s.cortex_inflation;
                  
              end
          

           case 'show_colorbar'
               
               if (s.(fieldname) == true)
                   app.ColorbarDropDown.Value = 'on';
                   app.params.CBar_on = 1;
               else
                   app.ColorbarDropDown.Value = 'off';
                   app.params.CBar_on = 0;
               end
                  
                   
           case 'colormap'
               
               % if user passed an unimplemented colormap, add it
               % to the colormap pulldown. This runs the risk of
               % the render crashing if map name doesn't exist
               % (e.g. because typo) but that's lesser evil than
               % restricting user to our predefined menu offerings
                             
               if ~ismember(s.(fieldname),app.ColormapDropDown.Items)                 
                    app.ColormapDropDown.Items{end} = s.(fieldname);                  
               end
                   
               app.ColormapDropDown.Value =  s.(fieldname);
               app.params.Cmap = s.(fieldname);
               
               
            case 'view'
                     
               % don't let the user specify an unimplemented view
                   
               if ismember(s.(fieldname),app.ViewDropDown.Items)
                   
                   app.ViewDropDown.Value =  s.(fieldname);
                   app.params.view = s.(fieldname);
                   
               end
               
               
            case 'interpolation'
                   
               if ismember(s.interpolation,app.InterpolationDropDown.Items)
                       
                  app.InterpolationDropDown.Value = s.interpolation;
                   
                  if (strcmp(s.interpolation,'linear'))
                       app.params.OL = 0;
                   else
                       app.params.OL = 1;
                  end
                   
               end
         
 
            case 'plot_positive_only'
                              
                if (s.(fieldname))
                   app.PDCheckbox.Value = 1;
                   app.params.PD = 1;
                else
                   app.PDCheckbox.Value = 0;
                   app.params.PD = 0;
                end


            otherwise
                   
                s1 = 'Unimplemented render setting. Ignoring...';
                s2 = sprintf('Bad setting %s', fn{index});
                uialert(app.NeuroDOTRenderToolUIFigure,s1,s2);
                    
        end % switch

    end % loop

end % apply_settings()
   

end % methods 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function NDRT_init(app, varargin)
             
            % check for NeuroDOT
            
            if ~exist('PlotInterpSurfMesh','file')
                delete(app); % no ND = fatal
                error('Cannot find NeuroDOT. Exiting...');
            end
   
            % max calculated when overlay is read but init now in case
            % user toggles radio button before an overlay is selected
            
            app.overlay_max = 1;
 
            % setup default render params -- some/all of these will
            % be overwritten if user passed in custom settings (see below)
            
            app.setdefaultparams();
            
            % hide the frame spinner until/if user
            % selects a multiframe file to display
            
            app.frame = 1;          
            app.toggle_multiframe_select(1);
                        
            % if we were passed variables on startup, process
            % based on number of variables passed (see help)           

            % app main is foo(app,varargin) so we test nargin > 1 (not > 0)
             
            if (nargin > 1)

                % an optional inital settings struct can be passed as
                % the final optional parameter -- test for this and apply
                % before processing any other passed variables
                
                if (app.is_settings_struct(varargin{end}))
                    app.apply_settings(varargin{end});
                    adjusted_nargin = nargin - 1;
                else
                    adjusted_nargin = nargin;
                end
                
                switch adjusted_nargin
                    
                    case 1 %  varargin = initial_settings (handled above) 
                        
                        % no fall-though here...
                    
                    case 2 %  varargin = overlay fname (fullpath)
                        
                        % sanity check
                        
                        if ~ischar(varargin{1}) || ~exist(varargin{1},'file')
                            delete(app);
                            error(['Usage: ' mfilename '(overlay-file)']);
                        end
                        
                        [ pathname,filename,ext ] = fileparts(varargin{1});
                        if isempty(pathname); pathname = pwd; end
                        app.loadfile(pathname,[filename ext]);
                        
                    case 3 % varargin = [ overlaydata, headerdata ]
                        
                        if ndims(varargin{1}) < 3 || ~isstruct(varargin{2})
                            delete(app);
                            error(['Usage: ' mfilename '(overlay-data,overlay-header)']);
                        end
                      
                        app.overlay = varargin{1};
                        app.overlay_header = varargin{2};
                        
                        if isfield(app.overlay_header,'filename')
                            [~,n,e] = fileparts(app.overlay_header.filename);
                            filename = [ n e ];
                        else
                            filename = 'passed data';
                        end
                        
                        app.update_gui_and_render(filename);
                        
                    case 5 % varargin = [ overlaydata, headerdata, meshL, meshR ]
                        
                        if ndims(varargin{1}) < 3 || ~isstruct(varargin{2})  
                            delete(app);
                            error(['Usage: ' mfilename '(overlay-data,overlay-header,L-mesh-struct,R-mesh-struct)']);
                        end
                        
                        if ~isstruct(varargin{3}) || ~isfield(varargin{3},'nodes')
                            delete(app);
                            error(['Usage: ' mfilename '(overlay-data,overlay-header,L-mesh-struct,R-mesh-struct)']);
                        end
                        
                        if ~isstruct(varargin{4}) || ~isfield(varargin{4},'nodes')
                            delete(app);
                            error(['Usage: ' mfilename '(overlay-data,overlay-header,L-mesh-struct,R-mesh-struct)']);
                        end
                                         
                        app.overlay = varargin{1};
                        app.overlay_header = varargin{2};
                        
                        if isfield(app.overlay_header,'filename')
                            [~,n,e] = fileparts(app.overlay_header.filename);
                            filename = [ n e ];
                        else
                            filename = 'passed data';
                        end                       
                        
                        app.MESH_L = varargin{3};
                        app.MESH_R = varargin{4};
                        
                        app.update_mesh_gui('passed data');
                         
                        app.update_gui_and_render(filename);
                        
                    otherwise
                        delete(app);
                        error('Unrecognized startup syntax. See Help for usage...');
                end
                
            end
            
            
        end

        % Button pushed function: SelectnewButton
        function SelectnewButtonPushed(app, event)
                          
            % uigetfile file filtering is broken on OS-X
            % ergo, we allow any file to be selected (*.*)
            % and do sanity checking in loadfile
            
            [filename,pathname,index] = uigetfile('*.*','Select overlay file (.nii,.mat, or .4dfp)');

            if (index == 0); return; end % user cancelled

            app.loadfile(pathname,filename);
 
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            app.frame = app.FrameSpinner.Value;
            app.render();
        end

        % Value changed function: ColorscaleSlider
        function ColorscaleSliderValueChanged(app, event)
 
            value = app.ColorscaleSlider.Value;
            % neurodot doesn't like literal zero scale
            if (abs(value)<eps); value = eps; end
            
            % update the actual value used internally
            if (app.absoluteButton.Value == 1)
                app.params.Scale = value;
            else                
                app.params.Scale = value * app.overlay_max;
            end
            % update thresholds to reflect new scale value
            threshvalue = app.PThreshEditField.Value/100;
            app.params.Th.P = threshvalue * app.params.Scale;
            threshvalue = -app.NThreshEditField.Value/100;
            app.params.Th.N = threshvalue * app.params.Scale;

            % make sure edit field mirrors slider
            % but display "0" rather than eps for minimum
            value_to_display = value;
            if (value_to_display < 2*eps); value_to_display = 0; end
            app.ColorscaleEditField.Value = value_to_display;
            app.render();
            
        end

        % Value changing function: ColorscaleSlider
        function ColorscaleSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ColorscaleEditField.Value = changingValue;
        end

        % Value changed function: ColorscaleEditField
        function ColorscaleEditFieldValueChanged(app, event)
 
            value = app.ColorscaleEditField.Value;
 
            % neurodot doesn't like literal zero scale
            if (abs(value)<eps); value = eps; end
            % let user extend upper limit via text entry
            temp = app.ColorscaleSlider.Limits;
            if (value > temp(2))
                app.ColorscaleSlider.Limits = [ temp(1) value ];
            end

            % make sure slider mirrors edit field
            app.ColorscaleSlider.Value = value;
            
            % update the actual value used internally
            if (app.absoluteButton.Value == 1)
                app.params.Scale = value;
            else                
                app.params.Scale = value * app.overlay_max;
            end
            
            % update thresholds to reflect new scale value
            threshvalue = app.PThreshEditField.Value/100;
            app.params.Th.P = threshvalue * app.params.Scale;
            threshvalue = -app.NThreshEditField.Value/100;
            app.params.Th.N = threshvalue * app.params.Scale;

            app.render();
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            value = app.ColorscaleEditField.Value;
            if (app.absoluteButton.Value == 1)
                app.params.Scale = value;
            else                
                app.params.Scale = value * app.overlay_max;
            end 
            app.render();
        end

        % Value changing function: NThreshSlider
        function NThreshSliderValueChanging(app, event)
            changingValue = event.Value;
            app.NThreshEditField.Value = 100-changingValue;
        end

        % Value changed function: NThreshSlider
        function NThreshSliderValueChanged(app, event)
            % we want the N slider to go -100 to 0
            % but appdesigner only allows increasing values
            % ergo, flip the slider value behind the scenes...
            % (see also NThreshSliderValueChanging)
            value = app.NThreshSlider.Value;
            display_value = 100-value;
            app.NThreshEditField.Value = display_value;
            % NeuroDOT expects N thresh to be negative
            % the absolute value is proportion of colorscale
            internal_value = -display_value/100;
            app.params.Th.N = internal_value * app.params.Scale;
            app.render();

        end

        % Value changed function: NThreshEditField
        function NThreshEditFieldValueChanged(app, event)
            value = app.NThreshEditField.Value;
            app.NThreshSlider.Value = 100-value;
            internal_value = -value/100;
            app.params.Th.N = internal_value * app.params.Scale;
            app.render();
        end

        % Value changing function: PThreshSlider
        function PThreshSliderValueChanging(app, event)
            changingValue = event.Value;
            app.PThreshEditField.Value = changingValue;
        end

        % Value changed function: PThreshSlider
        function PThreshSliderValueChanged(app, event)
            value = app.PThreshSlider.Value;
            app.PThreshEditField.Value = value;
            app.params.Th.P = (value/100) * app.params.Scale;
            app.render();
        end

        % Value changed function: PThreshEditField
        function PThreshEditFieldValueChanged(app, event)
            value = app.PThreshEditField.Value;
            app.PThreshSlider.Value = value;
            app.params.Th.P = (value/100) * app.params.Scale;
            app.render();  
        end

        % Value changed function: ColorbarDropDown
        function ColorbarDropDownValueChanged(app, event)
            value = app.ColorbarDropDown.Value;
            if strcmp(value,'on')
                app.params.CBar_on = 1;
            else
                app.params.CBar_on = 0;
            end
            app.render()
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            app.params.Cmap = app.ColormapDropDown.Value;
            app.render();
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            app.params.view = app.ViewDropDown.Value;
            app.render();        
        end

        % Value changed function: InflationDropDown
        function InflationDropDownValueChanged(app, event)
            value = app.InflationDropDown.Value;
            switch value
                case 'none'
                    app.params.ctx = 'std';
                case 'some'
                    app.params.ctx = 'inf';
                case 'max'
                    app.params.ctx = 'vinf';
            end
            app.render();
        end

        % Value changed function: InterpolationDropDown
        function InterpolationDropDownValueChanged(app, event)
            value = app.InterpolationDropDown.Value;
            if (strcmp(value,'linear'))
                app.params.OL = 0;
            else
                app.params.OL = 1;
            end
            app.render(); 
        end

        % Value changed function: PDCheckbox
        function PDCheckboxValueChanged(app, event)
            app.params.PD = app.PDCheckbox.Value;
            app.render();
        end

        % Button pushed function: TemplateSelectButton
        function TemplateSelectButtonPushed(app, event)
   
            % uigetfile file filtering is broken on OS-X
            % ergo, we allow any file to be selected (*.*)
            % and do sanity checking after load
 
            [filename,pathname,index] = uigetfile('*.*','Select cortical mesh mat file');

            if (index == 0); return; end % user cancelled
            
            [~,~,e] = fileparts(filename);
            
            if ~strcmp(e,'.mat')
                s1 = 'Not a mat file. Keeping current mesh.';
                s2 = sprintf('Invalid mesh file: %s', filename);
                uialert(app.NeuroDOTRenderToolUIFigure,s1,s2);
                return;            
            end
          
            MESH_L = [];           
            MESH_R = [];
            
            temp = load(fullfile(pathname,filename));
                     
            if isstruct(temp)
               
                if isfield(temp,'MESH_L'); MESH_L = temp.MESH_L; end
                if isfield(temp,'MESH_R'); MESH_R = temp.MESH_R; end
                
                if isfield(temp,'MNIl'); MESH_L = temp.MNIl; end
                if isfield(temp,'MNIr'); MESH_R = temp.MNIr; end
                
                if isfield(temp,'Anat')
                    if isfield(temp.Anat,'CtxL'); MESH_L = temp.Anat.CtxL; end
                    if isfield(temp.Anat,'CtxR'); MESH_R = temp.Anat.CtxR; end
                end
                
            end
            
            if isempty(MESH_L) || isempty(MESH_R)
            s1 = 'File must contain MESH_L/R, MNIl/r or Anat.CtxL/R. Keeping current mesh.';
            s2 = sprintf('Invalid mesh file: %s', filename);
            uialert(app.NeuroDOTRenderToolUIFigure,s1,s2);
            return;
            end
            
            app.MESH_L = MESH_L;
            app.MESH_R = MESH_R;
                      
            app.update_mesh_gui(filename);
            
            app.render();
           
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
            app.overlay_header
            app.params
            disp('params.Th:')
            app.params.Th
        end

        % Button pushed function: RestoreDefaultsButton
        function RestoreDefaultsButtonPushed(app, event)
            app.setdefaultparams();
            app.update_gui_and_render(app.OverlayEditField.Value);

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
          
            % bounce if help win is already open...

            if ~isempty(findall(0,'type','figure','name','NeuroDOT Render Help'))
                return; 
            end
        
            % fig = uifigure('Position',[50 500 600 400],...
            fig = uifigure('Position',[50 400 600 400],...
                'Name','NeuroDOT Render Help',...
                'Resize','on');

            help_text = help('neurodot_render_tool_help');             
                      
            uitextarea(fig,...
                'Position',[10 50 580 320],...
                'Value',help_text,...
                'FontWeight','bold',...
                'FontName','Courier');
                       
            uibutton(fig,...
                'Position',[485 10 60 30],...
                'FontSize',14,...
                'Text','close',...
                'ButtonPushedFcn', @(btn,event) delete(fig));
            
            app.helpwindow = fig;

        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            % "quit" closes all open app windows
            % -- use red button to leave other windows open
            try close(app.helpwindow); catch; end
            try close(app.params.fig_handle);catch;end
            pause(0.1);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeuroDOTRenderToolUIFigure = uifigure('Visible', 'off');
            app.NeuroDOTRenderToolUIFigure.Name = 'NeuroDOT Render Tool';
            app.Panel = uipanel(app.NeuroDOTRenderToolUIFigure);
            app.HelpButton = uibutton(app.Panel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.RestoreDefaultsButton = uibutton(app.Panel, 'push');
            app.RestoreDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultsButtonPushed, true);
            app.RestoreDefaultsButton.Text = 'Restore defaults';
            app.PrintButton = uibutton(app.Panel, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Text = 'Print current settings in command window';
            app.divider_04 = uilabel(app.Panel);
            app.divider_04.Text = '____________________________________________________';
            app.TemplateSelectButton = uibutton(app.Panel, 'push');
            app.TemplateSelectButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateSelectButtonPushed, true);
            app.TemplateSelectButton.Text = 'Select new...';
            app.PDCheckbox = uicheckbox(app.Panel);
            app.PDCheckbox.ValueChangedFcn = createCallbackFcn(app, @PDCheckboxValueChanged, true);
            app.PDCheckbox.Text = 'Only plot positive overlay values';
            app.divider_02 = uilabel(app.Panel);
            app.divider_02.Text = '____________________________________________________';
            app.plus_thresh_label = uilabel(app.Panel);
            app.plus_thresh_label.Text = '%';
            app.minus_thresh_label = uilabel(app.Panel);
            app.minus_thresh_label.Text = '%';
            app.PThreshEditField = uieditfield(app.Panel, 'numeric');
            app.PThreshEditField.Limits = [0 100];
            app.PThreshEditField.ValueDisplayFormat = '%11.3g';
            app.PThreshEditField.ValueChangedFcn = createCallbackFcn(app, @PThreshEditFieldValueChanged, true);
            app.NThreshEditField = uieditfield(app.Panel, 'numeric');
            app.NThreshEditField.Limits = [0 100];
            app.NThreshEditField.ValueDisplayFormat = '%11.3g';
            app.NThreshEditField.ValueChangedFcn = createCallbackFcn(app, @NThreshEditFieldValueChanged, true);
            app.PThreshSlider = uislider(app.Panel);
            app.PThreshSlider.MajorTicks = [];
            app.PThreshSlider.ValueChangedFcn = createCallbackFcn(app, @PThreshSliderValueChanged, true);
            app.PThreshSlider.ValueChangingFcn = createCallbackFcn(app, @PThreshSliderValueChanging, true);
            app.PThreshSlider.MinorTicks = [];
            app.NThreshSlider = uislider(app.Panel);
            app.NThreshSlider.MajorTicks = [];
            app.NThreshSlider.ValueChangedFcn = createCallbackFcn(app, @NThreshSliderValueChanged, true);
            app.NThreshSlider.ValueChangingFcn = createCallbackFcn(app, @NThreshSliderValueChanging, true);
            app.NThreshSlider.MinorTicks = [];
            app.NThreshSlider.Value = 100;
            app.ThresholdLabel = uilabel(app.Panel);
            app.ThresholdLabel.Text = '(-)         Threshold         (+)';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.absoluteButton = uiradiobutton(app.ButtonGroup);
            app.absoluteButton.Text = 'absolute';
            app.absoluteButton.Value = true;
            app.xmaxoverlayButton = uiradiobutton(app.ButtonGroup);
            app.xmaxoverlayButton.Text = ' x max(overlay)';
            app.colorscaleEditFieldLabel = uilabel(app.Panel);
            app.colorscaleEditFieldLabel.Text = '+/-';
            app.divider_01 = uilabel(app.Panel);
            app.divider_01.Text = '____________________________________________________';
            app.SelectnewButton = uibutton(app.Panel, 'push');
            app.SelectnewButton.ButtonPushedFcn = createCallbackFcn(app, @SelectnewButtonPushed, true);
            app.SelectnewButton.Text = 'Select new...';
            app.FrameSpinner = uispinner(app.Panel);
            app.FrameSpinner.Limits = [0 Inf];
            app.FrameSpinner.ValueDisplayFormat = '%d';
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameLabel = uilabel(app.Panel);
            app.FrameLabel.Text = 'Frame 0/000';
            app.QuitButton = uibutton(app.Panel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.OverlayEditFieldLabel = uilabel(app.Panel);
            app.OverlayEditFieldLabel.Text = 'Overlay';
            app.OverlayEditField = uieditfield(app.Panel, 'text');
            app.OverlayEditField.Value = '<click "Select new">';
            app.ColorscaleSliderLabel = uilabel(app.Panel);
            app.ColorscaleSliderLabel.Text = {'Color scale'; ''};
            app.ColorscaleSlider = uislider(app.Panel);
            app.ColorscaleSlider.MajorTicks = [];
            app.ColorscaleSlider.ValueChangedFcn = createCallbackFcn(app, @ColorscaleSliderValueChanged, true);
            app.ColorscaleSlider.ValueChangingFcn = createCallbackFcn(app, @ColorscaleSliderValueChanging, true);
            app.ColorscaleSlider.MinorTicks = [];
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.ColorscaleEditField = uieditfield(app.Panel, 'numeric');
            app.ColorscaleEditField.Limits = [0 Inf];
            app.ColorscaleEditField.ValueChangedFcn = createCallbackFcn(app, @ColorscaleEditFieldValueChanged, true);
            app.ColorbarDropDownLabel = uilabel(app.Panel);
            app.ColorbarDropDownLabel.Text = 'Colorbar';
            app.ColorbarDropDown = uidropdown(app.Panel);
            app.ColorbarDropDown.Items = {'on', 'off'};
            app.ColorbarDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorbarDropDownValueChanged, true);
            app.ColorbarDropDown.Value = 'on';
            app.ColormapDropDownLabel = uilabel(app.Panel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.Panel);
            app.ColormapDropDown.Items = {'jet', 'hot', 'cool', 'parula', 'gray'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'jet';
            app.ViewDropDownLabel = uilabel(app.Panel);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.Panel);
            app.ViewDropDown.Items = {'lat', 'dorsal', 'post'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'lat';
            app.InflationDropDown = uidropdown(app.Panel);
            app.InflationDropDown.Items = {'none', 'some', 'max'};
            app.InflationDropDown.ValueChangedFcn = createCallbackFcn(app, @InflationDropDownValueChanged, true);
            app.InflationDropDown.Value = 'none';
            app.InflationDropDownLabel = uilabel(app.Panel);
            app.InflationDropDownLabel.Text = 'Inflation';
            app.InterpolationDropDown = uidropdown(app.Panel);
            app.InterpolationDropDown.Items = {'linear', 'nearest'};
            app.InterpolationDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpolationDropDownValueChanged, true);
            app.InterpolationDropDown.Value = 'linear';
            app.InterpolationDropDownLabel = uilabel(app.Panel);
            app.InterpolationDropDownLabel.Text = 'Interpolation';
            app.MeshEditField = uieditfield(app.Panel, 'text');
            app.MeshEditField.Value = 'MNI164k_big.mat';
            app.MeshEditFieldLabel = uilabel(app.Panel);
            app.MeshEditFieldLabel.Text = 'Mesh';
            % Show the figure after all components are created
            app.NeuroDOTRenderToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = neurodot_render_tool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeuroDOTRenderToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)NDRT_init(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuroDOTRenderToolUIFigure)
        end
    end
end