classdef pop1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label_currentScale  matlab.ui.control.Label
        CheckBox            matlab.ui.control.CheckBox
        Label_setScale      matlab.ui.control.Label
        setScale            matlab.ui.control.NumericEditField
        DoneButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        Caller % calling app public properties
        values % cap manipulation params from calling app
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main, values)
            movegui(app.UIFigure,"center");
            
            app.Caller = main;
            app.values = values;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            state = app.CheckBox.Value;
            if state == 1
                app.Label_setScale.Enable = 0;
                app.setScale.Enable = 0;
            else
                app.Label_setScale.Enable = 1;
                app.setScale.Enable = 1;
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            state = app.CheckBox.Value;
            if state == 1
                app.Caller.dS_UI = app.values.Scale;
            else
                app.Caller.dS_UI = app.Caller.setScale.Value;
            end  
            
            uiresume(app.Caller.UIFigure)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label_currentScale = uilabel(app.UIFigure);
            app.Label_currentScale.Text = 'Use current Scale parameter:';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.Label_setScale = uilabel(app.UIFigure);
            app.Label_setScale.Enable = 'off';
            app.Label_setScale.Text = 'Set new Scale parameter:';
            app.setScale = uieditfield(app.UIFigure, 'numeric');
            app.setScale.Enable = 'off';
            app.setScale.Value = 1;
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pop1(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end